classdef ConfigVars < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigureinputvariablesUIFigure  matlab.ui.Figure
        SubmitButton           matlab.ui.control.Button
        VariableDropDownLabel  matlab.ui.control.Label
        VariableDropDown       matlab.ui.control.DropDown
        OptimiseCheckBox       matlab.ui.control.CheckBox
        UpdateButton           matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
        LBEditFieldLabel       matlab.ui.control.Label
        LBEditField            matlab.ui.control.EditField
        UBEditFieldLabel       matlab.ui.control.Label
        UBEditField            matlab.ui.control.EditField
        ValueEditFieldLabel    matlab.ui.control.Label
        ValueEditField         matlab.ui.control.EditField
        TextArea               matlab.ui.control.TextArea
        InputvariablesLabel    matlab.ui.control.Label
        TableOutput            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp          % Main app object
        setVars             % The different variables that are avaiable to be selected (manually coded in for error checking)
        setDOrC             % Labels the variables above as descrete (D) or continous (C) in their respective order
        setLimits           % Defines the max upper limit and min lower limt for each C variable, as well as the allowed D variables in their respective order
        varFileName         % The name of the file that stores all the data about the system
        loadedVars          % The loaded variable file table that is read to display the selected variables
        varColNames         % The column names of the variable table
        
    end
    
    methods (Access = private)
 
%--------------------------------------------------------------------------------------------------------------------------------        
        
        %% This function checks if the set variables saved 
        % in the file are vaild. finalVar is the returned table, which is 
        % either empty, set to default or the actual read one if an error
        % is not triggered. Message displays the error/prompt message to
        % the user. Inputs are  varFileName which is the name of the
        % variable file being read, setVars are the names of the set
        % variables, setDOrC is the label as descrete or continous
        % respectivly and setLimits are the limits of all the C or D vars
        % respectivly. If first load = true, then if the file errors it
        % will set it as the default settings. If false it will return an
        % empty file.
        function [finalVar,message] = checkVarFile(app, varFileName, setVars, setDOrC, setLimits, firstLoad)
            
            % Flag for when error occurs
            ERROR = false;
            newVars = []; % Predefine incase error with file loading occurs
           
            % Attempt to load the file - if successfull then undergo error
            % checking
            try
                vars = load(varFileName);
                vars = struct2cell(vars);
                vars = vars{1};
                loadVars = string(vars.Name);
                loadDOrC = string(vars.DiscreteOrContinuous);
                loadData = vars.Data;
                loadOptimise = vars.Optimise;
            
            % If file fails to load, then setup a 'default' file to replace
            % it.
            catch 
                % Create table with the vaild list names. Set all limits
                % and each variable as being optimised (fastest way to do
                % it).
                newVars = table(app.setDOrC, app.setVars, app.setLimits',ones(length(app.setVars),1));
                % Set the column names do the default
                newVars.Properties.VariableNames = app.varColNames;
                
                % Error now = true and the newVars matrix is no longer empty, so
                % last if statement in function can display the correct error
                % messaage
                ERROR = true; 
            end
            
            %% Ensure the file length (total number of vars) is the same as the default expected
            if ERROR == false
                if length(setVars) ~= length(loadVars) % If the number of expected vars is different to the read vars
                    ERROR = true; % Set error flag which prevents any future errors from trigering
                    message = "Read file length is different to expected."; % Sets the error message
                end
            end
            
            % Use a different ERROR if to portion out code to make it
            % easier to read.
            
            %% Ensure that the order of variables read is the same as the default
            if ERROR == false
                % Loop through each var to perform the analysis
                for var = 1:length(loadVars)
                    % Checks the name order is correct
                    if loadVars(var) ~= setVars(var)
                        ERROR = true; % Set error flag which prevents any future errors from trigering
                        message = "Order of vars is not the same."; % Sets the error message
                        break % Ends for loop so it does not continue cycling through once it has found an error
                    end
                    % Checks if the var is labeled correctly as descrete or continous 
                    if loadDOrC(var) ~= setDOrC(var)
                        ERROR = true; % Set error flag which prevents any future errors from trigering
                        message = "Order of vars is not the same."; % Sets the error message
                        break % Ends for loop so it does not continue cycling through once it has found an error
                    end   
                end
            end
            
            % Use a different ERROR if to portion out code to make it
            % easier to read.
            
            % Large portion of the error checking that goes through each
            % variable to ensure that the different combinations of
            % descrete and continous as well as if the variable is being
            % optimised or not make sense. E.g. if variable is 'C' and
            % optimisation = true, then you would expect in the data
            % section of the code a lower and upper bound.
            if ERROR == false
                
                % Loops though each variable
                for var = 1:length(loadVars)
                    
                    % FIRST CHECK - if optimise = 1 and it is a continous var
                    if (loadOptimise(var) == 1) && (loadDOrC(var) == 'C')
                        
                        % Check that there are only two numbers in the data matrix 
                        % which represent the min and max
                        if length(loadData{var}) ~= 2 % If the length of the matrix is not equal to 2, trgger  error
                            ERROR = true; % Set error flag which prevents any future errors from trigering
                            message = "Each optimised continous variable must have only 2 values.";  % Sets the error message
                            break % Ends for loop so it does not continue cycling through once it has found an error
                            
                        % Now its been varified there are two values (min and max),
                        % check if min value set is larger than max
                        elseif loadData{var}(1) > loadData{var}(1) % If min value set is larger than max, trigger error
                            ERROR = true; % Set error flag which prevents any future errors from trigering
                            message = "Min limit cannot be greater than max.";  % Sets the error message
                            break % Ends for loop so it does not continue cycling through once it has found an error
                        
                        % Now varified max limit is greater than the min, check
                        % both vars to make sure they are within the set limits
                        
                        % If the min value is less than the set limit or
                        % the max value is greater than the set limit,
                        % trigger the error
                        elseif (loadData{var}(1) < setLimits{var}(1)) || (loadData{var}(2) > setLimits{var}(2))
                            ERROR = true; % Set error flag which prevents any future errors from trigering
                            message = "Optimised continous variables must be betwen set limits.";  % Sets the error message
                            break % Ends for loop so it does not continue cycling through once it has found an error
                        end 
                    
                    % SECOND CHECK - If  optimise = 1 and it is a continous var
                    elseif (loadOptimise(var) == 0) && (loadDOrC(var) == 'C')
                        
                        % Make sure that only one value is present
                        if length(loadData{var}) ~= 1 % If the length of the matrix is not equal to 1, trigger error
                            ERROR = true; % Set error flag which prevents any future errors from trigering
                            message = "Each constant continous var must only have one value.";  % Sets the error message
                            break % Ends for loop so it does not continue cycling through once it has found an error
                        
                        % Now it is known only one var is present, make sure it is between the limits
                        
                        % If the value is less than the set limit or the value is greater than the set limit,
                        % trigger the error
                        elseif  (loadData{var} < setLimits{var}(1)) || (loadData{var} > setLimits{var}(2))
                            ERROR = true; % Set error flag which prevents any future errors from trigering
                            message = "Each constant continous var must be between the set limits.";  % Sets the error message
                            break % Ends for loop so it does not continue cycling through once it has found an error
                        end
                    
                    % THIRD CHECK - If optimise = 1 and it is a descrete var
                    elseif (loadOptimise(var) == 1) && (loadDOrC(var) == 'D')
                        
                        % Make sure more than one var is selected (for optimisation)
                        if length(loadData{var}) <= 1 % If the length of the matrix is less than or equal too 1, trigger error
                            ERROR = true; % Set error flag which prevents any future errors from trigering
                            message = "Each optimised descrete variable must have more than one varable chosen.";  % Sets the error message
                            break % Ends for loop so it does not continue cycling through once it has found an error
                        
                        % If there is more than one var, make sure they are
                        % valid
                        
                        % If the elements in the data loaded match with any
                        % shown in the set limits, a 1 in the truth matrix
                        % is shown. All the elements must produce a 1 in
                        % the truth matrix for this to work. Therefore if
                        % the nnz of the truth matrix is equal too the
                        % length of the loaded data, then they all must be
                        % vaild. In this case if the length is different,
                        % and error is triggered as one or more must be
                        % invalid.
                        elseif nnz(ismember(loadData{var},setLimits{var})) ~= length(loadData{var})
                            ERROR = true; % Set error flag which prevents any future errors from trigering
                            message = "The optimised descrete var chosen is not recoginsed.";  % Sets the error message
                            break % Ends for loop so it does not continue cycling through once it has found an error
                        end
                   
                    % FORTH CHECK - If optimise = 0 and it is a descrete var
                    elseif (loadOptimise(var) == 0) && (loadDOrC(var) == 'D')
                        
                        % Make sure only one var is selected
                        if length(loadData{var}) ~= 1 %If the legnth of the var is not equal too 1, trigger error
                            ERROR = true; % Set error flag which prevents any future errors from trigering
                            message = "Each optimised descrete variable must have more than one varable chosen.";  % Sets the error message
                            break % Ends for loop so it does not continue cycling through once it has found an error
                            
                        % Given only one var is selected, make sure that it is
                        % allowed.
                        
                        % If the loaded data var is NOT a memember of the
                        % set limits var, then trigger the error
                        elseif ~ismember(str2num(loadData{var}),setLimits{var})
                            ERROR = true; % Set error flag which prevents any future errors from trigering
                            message = "The descrete var chosen is not recoginsed.";  % Sets the error message
                            break % Ends for loop so it does not continue cycling through once it has found an error
                        end
                    end
            
                end
            end
            
            % FINAL SUBMISSION
            
            % If no error has been triggered, then return the error checked
            % table
            if ERROR == false
                message = "Variables file sucessfully loaded."; % Sets the worked message
                finalVar = vars; % The loaded table is returned
                
            % If an error has been triggered AND the newVars file is NOT
            % empty, meaning the file failed to load in the try, catch
            % statement at the start, return the default vars table
            elseif ERROR == true && ~isempty(newVars)
                message = "Error loading file, changed to default settings."; % Sets the error message
                finalVar = newVars; % The default table is returned
                
            % If an error has been triggered AND it is the first load of
            % the file (when the change variables button is first clicked)
            % Display the error message and then notify the user the
            % settings have been changed to default.
            elseif ERROR == true && firstLoad == true
                
                % Create table with the vaild list names. Set all limits
                % and each variable as being optimised (fastest way to do
                % it).
                newVars = table(app.setDOrC, app.setVars, app.setLimits',ones(length(app.setVars),1));
                
                % Set the column names do the default
                newVars.Properties.VariableNames = app.varColNames;
                
                finalVar = newVars; % The default table is returned
                
                % Sets the error message and adds on the prompt that it has been set to default
                message = message + " Setting file to default settings.";
            
            % Finally if it is not the first load or the fail did not fail 
            % to load, it must mean that the user when submitting new
            % condtions has made an error. Return an empty finlVar file to
            % notify this and the message is already set when the error was
            % triggered
            else
                finalVar = [];
            end
        end
        
%--------------------------------------------------------------------------------------------------------------------------------
        
        
        %% This function updates the differnt labels and boxes 
        % so that only the ones that can be edited depending on if the
        % variable is D or C, or optimising or not, are shown.
        function updateVariableBoxes(app)
            % Retreves the current values of the optimise check box and the
            % variable selected
            
            optimise = app.OptimiseCheckBox.Value; %Optimise check box value. Checked = true, not checked = false
            % Finds the index of the variable in the setVars list depending
            % on the variable selected in the drop down box
            ind = strcmp(app.setVars,app.VariableDropDown.Value);
            %Uses this index to determine if the variable is D or C
            DOrC = app.setDOrC(ind);
            
            % Initaially dissables the visabiilty of the boxes to make the
            % code below more clear.
            app.LBEditField.Visible = false;
            app.LBEditFieldLabel.Visible = false;
            app.UBEditField.Visible = false;
            app.UBEditFieldLabel.Visible = false;
            app.ValueEditField.Visible = false;
            app.ValueEditFieldLabel.Visible = false;
            app.ListBox.Visible = false;
            app.ListBox.Multiselect = false;
            
            % Allow for selection of multiple variables when its a descrete
            % variable and otpimise is ticked.
            if (optimise == true) && (DOrC == "D")
                app.ListBox.Multiselect = true; % Allows for mutiple descrete variables to be selected
                app.ListBox.Visible = true; % Makes the list box visable
                app.ListBox.Items = string(app.setLimits{ind}); % Sets the correct items in the list box depending on the variable selected
                
            % Allow selection of only one variable when its a descrete
            % variable and optimise is not ticked.
            elseif (optimise == false) && (DOrC == "D")
                app.ListBox.Value = app.ListBox.Items(1); % Sets the value of the list box as the fist item, incase mutiple were prevosuly selected
                app.ListBox.Visible = true; % Makes the list box visable
                app.ListBox.Items = string(app.setLimits{ind}); % Sets the correct items in the list box depending on the variable selected
                
            % Allow input of max and min value when it is continous and
            % optimise is ticked
            elseif (optimise == true) && (DOrC == "C")
                app.LBEditField.Visible = true; %LB edit text box is made visable
                app.LBEditFieldLabel.Visible = true; %LB label is made visable
                app.UBEditField.Visible = true; %UB edit text box is made visable
                app.UBEditFieldLabel.Visible = true; %UB label is made visable
                
            % Allow input of only a single value when it is continous and
            % optimise is not ticked
            elseif (optimise == false) && (DOrC == "C")
                app.ValueEditField.Visible = true; % Value text box is made visable
                app.ValueEditFieldLabel.Visible = true; % Value label is made visable
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Set the variables, wether they are descrete or continous, and
            % their limits here.
            app.setVars = ["GradTime", "InitalOM", "FinalOM", "Temp", "IsoHoldTime", "IsoHoldTime2","GradTime2","Switch","InjVol", "FlowRate", "Column", "OMPhase", "Ph"]';
            app.VariableDropDown.Items = app.setVars;
            app.setDOrC = ["C"         "C"         "C"         "C"         "C"         "C"                "C"      "C"       "C"        "C"         "D"     "D"       "D"]';
            app.setLimits = {[1 60],    [5 95],     [5 95],  [25 60],     [0 60],         [0 60],     [0 60],     [0 100],  [0.2 5], [0.25 2], [1 2 3 4 5 6], [1 2 3 4], [1 2 3 4]};
            app.varFileName = "Variables.mat";
            app.varColNames = ["DiscreteOrContinuous","Name", "Data", "Optimise"];
            
            % Update the variable boxes
            updateVariableBoxes(app);
              
            % Check the variable file to make sure that it is correctly
            % configured. firstLoad = true as it is the first time loading
            % the file. This means that if an error is detected, it will
            % set it to the default automatically.
            [app.loadedVars, message] = checkVarFile(app, app.varFileName, app.setVars, app.setDOrC, app.setLimits, true);

            app.TextArea.Value = message; % Updates the text area
            
            % Converts the variables table into a cell matrix of characters:
            % this is the format it needs to be in for the 'print_table'
            % function. "'UniformOutput', false" is needed to do this.
            stringVars = cellfun(@string, table2cell(app.loadedVars), 'UniformOutput', false);
            % If an UB and LB, or multiple descrete variables are selected,
            % they need to be converted into one char cell instead of
            % multiple. This code finds the cell sizes where the length is 
            % >1 and then joins them with commas between each value.
            sizes = cell2mat(cellfun(@length, stringVars, 'UniformOutput', false));
            stringVars(sizes>1) = cellfun(@(x) strjoin(x, ", "), stringVars(sizes>1), 'UniformOutput', false);
            % Obtains the names of the headers for the top of the table
            headers = app.loadedVars.Properties.VariableNames;
            % Generates the neatly formated table for display. Needs to be
            % converted from char to string as the newline command wont
            % work with this and will display incorrectly in the log file.
            tableText = string(print_table(stringVars, {'%s'}, headers,'printBorder',1));
                
            app.TableOutput.Value = tableText;


        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)

            vars = app.loadedVars; % Sets the table to a temp variable so it can be saved
            save(app.varFileName, "vars"); % Overwrites the prevous save
            delete(app) % Exits the app
        end

        % Close request function: ConfigureinputvariablesUIFigure
        function ConfigureinputvariablesUIFigureCloseRequest(app, event)
            delete(app) % Exits the app without saving
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            optimise = app.OptimiseCheckBox.Value; %Optimise check box value. Checked = true, not checked = false
            % Finds the index of the variable in the setVars list depending
            % on the variable selected in the drop down box
            ind = strcmp(app.setVars,app.VariableDropDown.Value);
            % Uses this index to determine if the variable is D or C
            DOrC = app.setDOrC(ind);
            
            % Creates a temp var to make modfications to
            tempLoadedVars = app.loadedVars;

            % If the variable is being optimised and it is descrete
            if (optimise == true) && (DOrC == "D")
                % Set each selected value in the list box as the new value.
                % This requires the cellfun function as it is adding
                % multiple elements which are read as strings to be
                % transferred over as numbers.
                tempLoadedVars.Data{ind} = cellfun(@str2num,app.ListBox.Value);
                tempLoadedVars.Optimise(ind) = 1; % Set opt = true;

            % If the variable is NOT being optimised and it is descrete
            elseif (optimise == false) && (DOrC == "D")
                % Set the selected value in the list box as the new value.
                tempLoadedVars.Data{ind} = app.ListBox.Value;
                tempLoadedVars.Optimise(ind) = 0; % Set opt = false;
            
            % If the variable is being optimised and it is continous
            elseif (optimise == true) && (DOrC == "C")
                % Set the given upper and lower bound as the new value 
                tempLoadedVars.Data{ind} = [str2num(app.LBEditField.Value) str2num(app.UBEditField.Value)];
                tempLoadedVars.Optimise(ind) = 1; % Set opt = true;
            
            % If the variable is NOT being optimised and it is continous
            elseif (optimise == false) && (DOrC == "C")
                % Set the given value as the new value
                tempLoadedVars.Data{ind} = str2num(app.ValueEditField.Value);
                tempLoadedVars.Optimise(ind) = 0; % Set opt = false;
            end
            
            % Saves this as a tempory file that then gets read by the checkVarFile
            % function
            save tempVars.mat tempLoadedVars
            
            % Error checks the tempory saved file. this time is is not the
            % first time reading so firstLoad = false.
            [finalVar,message] = checkVarFile(app, "tempVars.mat", app.setVars, app.setDOrC, app.setLimits, false);
            
            % if the returned finalVar file is empty and error must have
            % occured. Display this message and the changes to the
            % variables are not made.
            if isempty(finalVar)
                app.TextArea.Value = message;
            
            % If the file does contain a table then it must have worked (or
            % been set to default).
            else
                
                % Converts the variables table into a cell matrix of characters:
                % this is the format it needs to be in for the 'print_table'
                % function. "'UniformOutput', false" is needed to do this.
                stringVars = cellfun(@string, table2cell(finalVar), 'UniformOutput', false);
                % If an UB and LB, or multiple descrete variables are selected,
                % they need to be converted into one char cell instead of
                % multiple. This code finds the cell sizes where the length is 
                % >1 and then joins them with commas between each value.
                sizes = cell2mat(cellfun(@length, stringVars, 'UniformOutput', false));
                stringVars(sizes>1) = cellfun(@(x) strjoin(x, ", "), stringVars(sizes>1), 'UniformOutput', false);
                % Obtains the names of the headers for the top of the table
                headers = finalVar.Properties.VariableNames;
                % Generates the neatly formated table for display. Needs to be
                % converted from char to string as the newline command wont
                % work with this and will display incorrectly in the log file.
                tableText = string(print_table(stringVars, {'%s'}, headers,'printBorder',1));
                
                app.TableOutput.Value = tableText;
                app.loadedVars = finalVar;

            end
        end

        % Value changed function: OptimiseCheckBox
        function OptimiseCheckBoxValueChanged(app, event)
            % Updates the variable boxes when the optimise check box is 
            % modified
            app.updateVariableBoxes()
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            % Updates the variable boxes when variable drop down is
            % modified
            app.updateVariableBoxes()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigureinputvariablesUIFigure = uifigure('Visible', 'off');
            app.ConfigureinputvariablesUIFigure.Name = 'Configure input variables';
            app.ConfigureinputvariablesUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConfigureinputvariablesUIFigureCloseRequest, true);
            app.SubmitButton = uibutton(app.ConfigureinputvariablesUIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.VariableDropDownLabel = uilabel(app.ConfigureinputvariablesUIFigure);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.ConfigureinputvariablesUIFigure);
            app.VariableDropDown.Items = {'GradTime', 'InitalOM', 'FinalOM', 'Temp', 'IsoHoldTime', 'InjVol', 'FlowRate', 'Column', 'OMPhase', 'Ph'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = 'GradTime';
            app.OptimiseCheckBox = uicheckbox(app.ConfigureinputvariablesUIFigure);
            app.OptimiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimiseCheckBoxValueChanged, true);
            app.OptimiseCheckBox.Text = 'Optimise';
            app.OptimiseCheckBox.Value = true;
            app.UpdateButton = uibutton(app.ConfigureinputvariablesUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ListBox = uilistbox(app.ConfigureinputvariablesUIFigure);
            app.LBEditFieldLabel = uilabel(app.ConfigureinputvariablesUIFigure);
            app.LBEditFieldLabel.Text = 'LB';
            app.LBEditField = uieditfield(app.ConfigureinputvariablesUIFigure, 'text');
            app.UBEditFieldLabel = uilabel(app.ConfigureinputvariablesUIFigure);
            app.UBEditFieldLabel.Text = 'UB';
            app.UBEditField = uieditfield(app.ConfigureinputvariablesUIFigure, 'text');
            app.ValueEditFieldLabel = uilabel(app.ConfigureinputvariablesUIFigure);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.ConfigureinputvariablesUIFigure, 'text');
            app.TextArea = uitextarea(app.ConfigureinputvariablesUIFigure);
            app.TextArea.Editable = 'off';
            app.InputvariablesLabel = uilabel(app.ConfigureinputvariablesUIFigure);
            app.InputvariablesLabel.Text = 'Input variables';
            app.TableOutput = uitextarea(app.ConfigureinputvariablesUIFigure);
            % Show the figure after all components are created
            app.ConfigureinputvariablesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigVars(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigureinputvariablesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigureinputvariablesUIFigure)
        end
    end
end