classdef HPLCapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HPLC                            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DefineinputsTab                 matlab.ui.container.Tab
        AreacutoffEditField             matlab.ui.control.NumericEditField
        AreacutoffEditFieldLabel        matlab.ui.control.Label
        HeightcutoffEditField           matlab.ui.control.NumericEditField
        HeightcutoffEditFieldLabel      matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        ChemstationdetectionButton      matlab.ui.control.RadioButton
        BuiltindetectionButton          matlab.ui.control.RadioButton
        VWDMonitorNumber                matlab.ui.control.Spinner
        PeakgroupEditField              matlab.ui.control.NumericEditField
        PeakgroupEditFieldLabel         matlab.ui.control.Label
        SmoothwidthEditField            matlab.ui.control.NumericEditField
        SmoothwidthEditFieldLabel       matlab.ui.control.Label
        AmpthresholdEditField           matlab.ui.control.NumericEditField
        AmpthresholdEditFieldLabel      matlab.ui.control.Label
        SlopethresholdEditField         matlab.ui.control.NumericEditField
        SlopethresholdEditFieldLabel    matlab.ui.control.Label
        CalcuateallresponcefunctionsCheckBox  matlab.ui.control.CheckBox
        RefwavelengthSpinner            matlab.ui.control.Spinner
        RefwavelengthSpinnerLabel       matlab.ui.control.Label
        ReprocessfilesButton            matlab.ui.control.Button
        WavelengthMultiUB               matlab.ui.control.Spinner
        ButtonGroup                     matlab.ui.container.ButtonGroup
        VWDmonitorButton                matlab.ui.control.RadioButton
        MultiwavelengthmonitorButton    matlab.ui.control.RadioButton
        SinglewavelengthmonitorButton   matlab.ui.control.RadioButton
        WavelengthMultiLB               matlab.ui.control.Spinner
        WavelengthSingle                matlab.ui.control.Spinner
        OptexpSpinner                   matlab.ui.control.Spinner
        NumberofexperimentsSpinnerLabel  matlab.ui.control.Label
        AutostartCheckBox               matlab.ui.control.CheckBox
        PauseOptButton                  matlab.ui.control.Button
        OptLamp                         matlab.ui.control.Lamp
        StartOptButton                  matlab.ui.control.Button
        Step3Label                      matlab.ui.control.Label
        PauseInitButton                 matlab.ui.control.Button
        InitLamp                        matlab.ui.control.Lamp
        StartInitButton                 matlab.ui.control.Button
        Step2Label                      matlab.ui.control.Label
        InfomationTextArea              matlab.ui.control.TextArea
        SamplePositionEditField         matlab.ui.control.EditField
        SamplePositionEditFieldLabel    matlab.ui.control.Label
        DatafilenameEditField           matlab.ui.control.EditField
        DatafilenameEditFieldLabel      matlab.ui.control.Label
        EqulibrationtimeSpinner         matlab.ui.control.Spinner
        EqulibrationtimeSpinnerLabel    matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        ObjectivefunctionsListBox       matlab.ui.control.ListBox
        ObjectivefunctionsListBoxLabel  matlab.ui.control.Label
        InitexpSpinner                  matlab.ui.control.Spinner
        NumberofinitalexperimentsSpinnerLabel  matlab.ui.control.Label
        HPLCDirectoryButton             matlab.ui.control.Button
        ReprocessdataButton             matlab.ui.control.Button
        ConfigvarButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        LoadVarsButton                  matlab.ui.control.Button
        ApplyVarsButton                 matlab.ui.control.Button
        HPLCDirectoryEditField          matlab.ui.control.EditField
        Step1Label                      matlab.ui.control.Label
        DatavisulisationTab             matlab.ui.container.Tab
        TimemaxSpinner                  matlab.ui.control.Spinner
        TimemaxSpinnerLabel             matlab.ui.control.Label
        YLowerSpinner                   matlab.ui.control.Spinner
        YlowerSpinnerLabel              matlab.ui.control.Label
        YUpperSpinner                   matlab.ui.control.Spinner
        YUpperSpinnerLabel              matlab.ui.control.Label
        PlotBestIntensities             matlab.ui.control.CheckBox
        ThreeDSet                       matlab.ui.control.CheckBox
        WavelengthSpinner               matlab.ui.control.Spinner
        WavelengthSpinnerLabel          matlab.ui.control.Label
        Step3Label_2                    matlab.ui.control.Label
        PlotparetodataCheckBox          matlab.ui.control.CheckBox
        VarListBox                      matlab.ui.control.ListBox
        ToggledebugCheckBox             matlab.ui.control.CheckBox
        Switchgraphstate                matlab.ui.control.Switch
        HPLCListBox                     matlab.ui.control.ListBox
        GraphAxes                       matlab.ui.control.UIAxes
        OptimisationtestTab             matlab.ui.container.Tab
        RepeatsimulationEditField       matlab.ui.control.NumericEditField
        RepeatsimulationEditFieldLabel  matlab.ui.control.Label
        zSlider                         matlab.ui.control.Slider
        zSliderLabel                    matlab.ui.control.Label
        ySlider                         matlab.ui.control.Slider
        ySliderLabel                    matlab.ui.control.Label
        xSlider                         matlab.ui.control.Slider
        xSliderLabel                    matlab.ui.control.Label
        TestfunctionsDropDown           matlab.ui.control.DropDown
        TestfunctionsDropDownLabel      matlab.ui.control.Label
        customEditField                 matlab.ui.control.EditField
        customEditFieldLabel            matlab.ui.control.Label
        InitalLHSexperimentsEditField   matlab.ui.control.NumericEditField
        InitalLHSexperimentsEditFieldLabel  matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        SelectedvariablesListBox        matlab.ui.control.ListBox
        SelectedvariablesListBoxLabel   matlab.ui.control.Label
        SelectedresponcefunctionsListBox  matlab.ui.control.ListBox
        SelectedresponcefunctionsListBoxLabel  matlab.ui.control.Label
        FitmodelandplotButton           matlab.ui.control.Button
        NumberofiterationsEditField     matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        OptalgorithmDropDown            matlab.ui.control.DropDown
        OptalgorithmDropDownLabel       matlab.ui.control.Label
        ModeltofitDropDown              matlab.ui.control.DropDown
        ModeltofitDropDownLabel         matlab.ui.control.Label
        ExperimentstofittomodelListBox  matlab.ui.control.ListBox
        ExperimentstofittomodelListBoxLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        liveplot                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        variables = []; % Contains the table with the differnt vairables that define the optimisation
        totExpNumb = 0; % The number of experiments
        initExpNumb = 0; % The number of intial experiments
        equalTime = 0; % The equlibration time between each run
        hplcDirectory = string; % Where the HPLC files are located
        inputVarNames = string; % Names of each input var
        initCondtions = []; % chromatogram of inital conditons defined by the latin hypercube function
        initResults = []; % Inital results that are stored
        currExp = 1; % The current experiment that is being investigated
        responceData = []; % A table that contains the responce data, depending on the ones selected
        varFileName = ""; % Name of the file that is loaded or gets saved containing all data
        allData = cell(1,2) % The cell that gets saving containing all the data
        graphPeaksData = cell(1,2); % Contains the x and y data as well as the peaks data for each file
        paused = false; % Sets the paused state
        samplePos = ""; % The sample position
        allVars = []; % All the variables that have been selected + the responce vars selected
        varOrder = []; % String list that contains the variables to be displayed in order in the desgin space
        algOutput = cell(1,2); % Cell that contains all the output of the opt function used
        ResultsDir = "Results\" % The folder that contains all the results
        logTrack; % Log file class that keeps track of everything that has happened
        logFileName = "logFile.txt" % The name that each log file gets saved as
        folderSave = ""; % The current file location of the saved data
        fileNameSave = "" % Name of the file being saved
        errorTime = 120; % Sets a default error out time of 120 minutes.
        simulateCondtions %Structure containing data for the simulated optimiastion
        txtForSimulation %Simluation text box text
        testFunctions = cell(2,1); % Contains all the different test functions that can be used to test the ability of each alorithm
        slopeThresh; %Hyperparamter for the peak picking function
        ampThresh; %Hyperparamter for the peak picking function
        smoothWidth; %Hyperparamter for the peak picking function
        peakGroup; %Hyperparamter for the peak picking function
        
        Settings; % Structure containing all the different setting values for the optimisation.

        chromatogramCounter = 1; % The current chromatogram number being analysied
        chromatogramNames = string; % All the chromatogram names used for the list box items
        chromatogramLable = string; % The displayed chromatogram name for the list box items
        currentWavelength = 3; % Current wavelength displayed on the graph
        chromatogramNumbShown = 1; % Current chromatogram number shown on the graph
        
        textArea = string; % The current string displayed in the list box
        
        algorithms = cell(2,1)% Named list of the algorithms that can be selected
        algSelected = []; % The algorithm actually selected to run
        responceFunctions = cell(2,1) % Named list of the responce function and actual function attached to it
        respSelected = []; % The responce functions actually selected to run
        
        wongCM; %colour map for colour blind pallette
        
    end
    
    properties (Access = private)
        DialogApp % Description
    end
    
    methods (Access = private)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function updateTextBox(app, append, text, log)
        if nargin == 3
            log = false;
        end
            
        % If append == true, then the text is appended to the already
        % current text and displayed in the message box. If false, then it
        % writes over all prevous messages displayed in the box.
        
            if append == true
                app.textArea = app.textArea + text;
            else
                app.textArea = text;
            end
            
            app.InfomationTextArea.Value = app.textArea;
            
            if log == true
                app.logTrack.info('updateTextBox', text)
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function resetUI(app)
            
            % Disable the buttons before variables and inital experiment
            % condtions are 'applied'.
            app.StartInitButton.Enable = false;
            app.PauseInitButton.Enable = false;
            app.StartOptButton.Enable = false;
            app.PauseOptButton.Enable = false;
            app.SamplePositionEditField.Enable = true;
            app.LoadVarsButton.Enable = true;
            app.ApplyVarsButton.Enable = true;
            app.ObjectivefunctionsListBox.Enable = true;
            app.DatafilenameEditField.Enable = true;
            app.AlgorithmDropDown.Enable = true;
            app.HPLCDirectoryEditField.Enable = true;
            app.InitexpSpinner.Enable = true;
            app.EqulibrationtimeSpinner.Enable = true;
            app.InitLamp.Color = [0.65 0.65 0.65];
            app.OptLamp.Color = [0.65 0.65 0.65];
            app.HPLCListBox.Items = {};
            app.ToggledebugCheckBox.Enable = false;
            app.Switchgraphstate.Enable = false;
            app.ReprocessdataButton.Enable = false;
            cla(app.GraphAxes,'reset');
            app.VarListBox.Items = {};
            
            
            % Wipe the text area
            app.InfomationTextArea.Value = "";
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function updateRespBox(app)
            

            % Set the option in the responce functions list to the first
            % option just to reset it.
            app.ObjectivefunctionsListBox.Value = app.responceFunctions{1,:}(1);
            % Finds out the algorithm that is slected
            respFunc = app.AlgorithmDropDown.Value;
            
            % If it is SNOBFIT
            if respFunc == "Snobfit"
                % Disable multiselect as it is a single-objective 
                % optimisation algorithm
                app.ObjectivefunctionsListBox.Multiselect = false;
            % If it is TS-EMO
            elseif respFunc == "LVBayesian"
                % Disable multiselect as it is a single-objective 
                % optimisation algorithm
                app.ObjectivefunctionsListBox.Multiselect = false;
            elseif respFunc == "TS-EMO"
                % Enable the multiselect as it is a multi-objective
                % optimisation algorithm
                app.ObjectivefunctionsListBox.Multiselect = true;
            elseif respFunc == "MVMOO"
                % Enable the multiselect as it is a multi-objective
                % optimisation algorithm
                app.ObjectivefunctionsListBox.Multiselect = true;
            elseif respFunc == "BayesianOptimiser"
                % Enable the multiselect as it is a multi-objective
                % optimisation algorithm
                app.ObjectivefunctionsListBox.Multiselect = false;    
            end
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function applyVars(app)
            % Obtains all the data and puts it into a single cell, which is
            % then read and displayed in the message box
            try
                % Create log file
                app.logTrack = log4m.getLogger(app.folderSave + "\" + app.logFileName);
                
                % Opens the variables file and reads the data. This data is in
                % a struct format so convert to cell and then read the first
                % (and only) entry.
                app.variables = load("Variables.mat");
                app.variables = struct2cell(app.variables);
                app.variables = app.variables{1,1};
                
                %Save the current settings
                app.saveSettings();
                
                app.respSelected = app.ObjectivefunctionsListBox.Value;
                % If there are multiple responce functions selected, it turns
                % them into a cell. Therefore this detects this and reverts it
                % back to a strign array if multiple are selected. If only one
                % is detected it is a char. Therefore convert it to a string.
                if iscell(app.respSelected)
                    app.respSelected = cellfun(@string, app.respSelected);
                else
                    app.respSelected = string(app.respSelected);
                end
                
                % Extract the optimsied variables and calucalte the inital
                % condtions
                
                % Extacts the data from each row in the variables table
                names = app.variables.Name';
                dorc = app.variables.DiscreteOrContinuous';
                opt =  app.variables.Optimise';
                data = app.variables.Data';
                
                % Builds the inital condtions based on the a Latin hypercube
                % sampling desgin and changes the upper and lower bounds based
                % on the values set
                tempData = zeros(app.initExpNumb, length(names));
                X = lhsdesign(app.initExpNumb,nnz(opt));  % Latin hypercube design
                counter = 1;
                for k = 1:length(names)
                    
                    % If the value is being optimised and is continous var
                    if opt(k) == 1 && dorc(k) == "C"
                        % Set value based on the upper and lower bounf
                        tempData(:,k) = round(X(:,counter).*(data{1,k}(2)-data{1,k}(1))+data{1,k}(1),2);        % adjustment of bounds
                        counter = counter + 1;
                        
                    % If the value is not being optimised and is a continous
                    % var
                    elseif opt(k) == 0 && dorc(k) == "C"
                        tempData(:,k) = data{1,k}(1);
                        
                    % If the value is discrete and is being optimise
                    % COMMENT
                    elseif opt(k) == 1 && dorc(k) == "D"
                        index = round(X(:,counter)*(length(data{k})-1),0)+1;
                        extendedData = data{k};
                        tempData(:,k) = extendedData(:,index)';
                        counter = counter + 1;
                        
                    % If the value is discrete and is not being optimised -
                    % currently just sets it to one value 
                    elseif opt(k) == 0 && dorc(k) == "D"
                        tempData(:,k) = str2num(data{1,k}(1));
                    end
                end
                
                % Converts all initial condtions into a table format
                app.initCondtions = array2table(tempData, "VariableNames", names);
    
                app.LoadVarsButton.Enable = false;
                app.StartInitButton.Enable = true;
                app.InitLamp.Color = [1 0 0]; %Turn inital condtions lamp to red (not running)
%                 app.ObjectivefunctionsListBox.Enable = false;
%                 app.DatafilenameEditField.Enable = false;
%                 app.AlgorithmDropDown.Enable = false;
%                 app.HPLCDirectoryEditField.Enable = false;
%                 app.InitexpSpinner.Enable = false;
%                 app.EqulibrationtimeSpinner.Enable = false;
%                 app.SamplePositionEditField.Enable = false;
                app.ReprocessdataButton.Enable = true;
                
                % Resets the current experiment back to 1;
                app.currExp = 1;
                
                % Setup inital results table
                app.initResults = cell2table(cell(1, length(app.respSelected)), "VariableNames",app.respSelected);
                
                % Set up the Var list box with all the variables
                app.allVars = [names app.respSelected];
                app.VarListBox.Items = app.allVars;
                
                % Reset the graph peaks data and algorithm output (wiping the prevous save)
                app.graphPeaksData = cell(1,2);
                app.algOutput = cell(1,2);

    
                % The function that displays the data submited in the text box
                app.DisplayData(true);
    
                % Save var file given condtions
                app.saveVarFile()
                
                app.logTrack.debug('applyVars', 'Variables successfuly initialised, saved and log file created.')
            catch
                app.logTrack.error('applyVars', 'Variables failed to initialise/save.')
                app.updateTextBox(true, "Variables failed to initialise/save.")
            end

        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function saveVarFile(app)
            
            % Set all the variables that have been saved.
            app.allData{1,1} = "Variables";
            app.allData{1,2} = app.variables;
            
            app.allData{2,1} = "InitalConditions";
            app.allData{2,2} = app.initCondtions;
            
            app.allData{3,1} = "InitalResults";
            app.allData{3,2} = app.initResults;
            
            app.allData{4,1} = "AlgSelected";
            app.allData{4,2} = app.algSelected;
            
            app.allData{5,1} = "InitExpNumb";
            app.allData{5,2} = app.initExpNumb;
            
            app.allData{6,1} = "Data";
            app.allData{6,2} = app.graphPeaksData;
            
            app.allData{7,1} = "AlgorithmOutput";
            app.allData{7,2} = app.algOutput;

            app.allData{8,1} = "Settings";
            app.allData{8,2} = app.Settings;
            

            % Saves this data to a temp variables
            data = app.allData;
            % Creates the new folder. If the directory already exists it
            % does not overwrite it.
            mkdir(app.folderSave)
            
            % Saves the data to ".mat" file. Overwrites it if it already
            % exists.
            save(app.folderSave + "\" + app.fileNameSave ,"data");
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function DisplayData(app,logData)
            if nargin == 1
                logData = false;
            end
            
            % Notfiys user that the variables have been loaded and wipes
            % prevous textbox data
            app.updateTextBox(true, "Variables loaded!" + newline + newline, logData)
            
            %***WRITE VARIABLES AND CONDTIONS***
            
            app.updateTextBox(true, "Conditions:" + newline + newline,logData)
            
            % Converts the variables table into a cell matrix of characters:
            % this is the format it needs to be in for the 'print_table'
            % function. "'UniformOutput', false" is needed to do this.
            stringVars = cellfun(@string, table2cell(app.variables), 'UniformOutput', false);
            
            % If an UB and LB, or multiple descrete variables are selected,
            % they need to be converted into one char cell instead of
            % multiple. This code finds the cell sizes where the length is 
            % >1 and then joins them with commas between each value.
            sizes = cell2mat(cellfun(@length, stringVars, 'UniformOutput', false));
            stringVars(sizes>1) = cellfun(@(x) strjoin(x, ", "), stringVars(sizes>1), 'UniformOutput', false);
            
            % Obtains the names of the headers for the top of the table
            headers = app.variables.Properties.VariableNames;
            
            % Generates the neatly formated table for display. Needs to be
            % converted from char to string as the newline command wont
            % work with this and will display incorrectly in the log file.
            tableText = newline + string(print_table(stringVars, {'%s'}, headers,'printBorder',1));
            
            % Updates the textbox with the formated data
            app.updateTextBox(true, tableText,logData);
            
            %Spaces between the next outputs
            app.updateTextBox(true, " " + newline + newline,logData);
            
            %***WRITE SETTINGS AND ALGORITHM CONDTIONS***
            
            % Displays the other variables set by the user
            app.updateTextBox(true, "Inital number of experiments: " + string(app.initExpNumb) + newline,logData)
            app.updateTextBox(true, "Equilbration time: " + string(app.equalTime) + newline,logData)
            app.updateTextBox(true, "HPLC directory: " + string(app.hplcDirectory) + newline,logData)
            app.updateTextBox(true, "Sample Postion: " + app.SamplePositionEditField.Value + newline,logData)
            % Updates the user what the responce functions selected are
            app.updateTextBox(true, "Optimisation algorithm selected: " + app.algSelected + newline,logData);
            app.updateTextBox(true, "Responce functions: " + join(app.respSelected, ', ') + newline,logData);
           
            %***WRITE CURRENT EXPERIMENTS AND RESULTS***
            
            % Prints the current optimisation condtions in the text box.
            % Requires a monospaced font to be selected in the text box.      
            app.updateTextBox(true, newline + "Experiments: " + newline + newline,logData);
            
            % Writes all the experiments currently run
            app.writeCurrExperiment(1:size(app.initCondtions,1), logData);
                
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function UpdateGraphList(app)
            
            % If the first element is empty, assuming that the file has
            % been saved but no runs have been done, make the list box
            % empty. Otherwise update the list box with all the names of
            % the HPLC file names that have been read.
            if isempty(app.graphPeaksData{1,1})
                % No data detected so set the names and labels blank
                app.chromatogramNames = {};
                app.chromatogramLable = {};
                
                % Update the list box
                app.HPLCListBox.Items = app.chromatogramLable;
                
                % Disable the buttons that allow plotting the debug mode
                % and swtiching the graph state when no data is detected
                app.ToggledebugCheckBox.Enable = false;
                app.Switchgraphstate.Enable = false;
            else
                % Extract all the chromatogram names and turn them from a
                % cell array into a string array
                app.chromatogramNames = cellfun(@string, app.graphPeaksData(:,1));
                % Make a copy that is ready to modify.
                app.chromatogramLable = app.chromatogramNames;
                
                % Append a number in frount of each chromatogram name to
                % display what experiment it is
                for label = 1:length(app.chromatogramLable)
                    app.chromatogramLable(label) = string(label) + ". " + app.chromatogramLable(label);
                end
                
                % Update the list box with the chromatrogram 'labels'
                app.HPLCListBox.Items = app.chromatogramLable;
                % Also update the simulation box
                app.ExperimentstofittomodelListBox.Items = app.chromatogramLable;
                
                % Selects the last (most recent) element in the list box
                % and displays it.
                app.HPLCListBox.Value = app.chromatogramLable(end);
                % Also update the simulation box location
                app.ExperimentstofittomodelListBox.Value = app.chromatogramLable(end);

                % Update the graph
                app.updateGraph()
                
                % Enable the buttons that allow plotting the debug mode
                % and swtiching the graph state 
                app.ToggledebugCheckBox.Enable = true;
                app.Switchgraphstate.Enable = true;
                
            end
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function CalcResponces(app)
            
            % Gives logical array of each responce function that has been
            % selected in the list of aviable responces functions. This is
            % used to point at the function handle, which then evaluates
            % the function given the condtions to yeild the responce
            % function values in each one chosen.
            
            %Creates an intal empty logal array with all zero.
            logicArry = false(1,length(app.responceFunctions{1,:}));
            
            % If only the responce variable selected is wanted to be
            % calculated (may be interested in having them all calcualted
            % if using a weighted responce function).
            if app.CalcuateallresponcefunctionsCheckBox.Value == false

                %Loops through each responce function selectd and finds the
                %location of the responce function in the list of the allowed
                %functions. Then adds this onto the logcal array.
                for resp = 1:length(app.respSelected)
                    % Finds the location of the responce function in the list 
                    % of the allowed functions
                    temp = strcmp(app.responceFunctions{1,:}, app.respSelected(resp));
                    
                    % Adds on this location. If == 1 then that responce
                    % function is being used, if == 0 then it is not.
                    logicArry = logicArry + temp;
                end
                
                logicArry = logical(logicArry);
            else
                logicArry = true(1,length(app.responceFunctions{1,:}));
            end

            % Delete the table and recreate it with the new headings.
            app.initResults = [];
            app.initResults = cell2table(cell(1, length(app.responceFunctions{1,:}(logicArry))), "VariableNames",app.responceFunctions{1,:}(logicArry));

            % Set the numbr of variables being looked at initally to 1
            numbVars = 1;
            %Loops through each logic (1 or 0) in the list of allowed
            %responce vars.
            for respFunc = 1:length(logicArry)
                % If == 1 this means it has been a selected as a responce
                % function to look at, so point to the function handle at
                % the same index but next cell and evaluate the function
                % given the inputs.
                if logicArry(respFunc) == 1
                    % This reprocesses all the data just in case there is a
                    % responce function that changes the other responce
                    % factors based on new data.
                    
                    for x = 1:app.currExp-1
                        
                        % If analysis is only occuring at a predefined wavelength 
                        if app.SinglewavelengthmonitorButton.Value == 1
                            % Find the selected wavelength
                            ind = find(app.graphPeaksData{x,3}==app.WavelengthSingle.Value);
                            
                            %Extract the function handle based on the index
                            funcHandle = app.responceFunctions{2,1}{respFunc};
                            
                            %Evaluate the function and set the data in the results
                            %in the column that depends on numbVars so it is in the
                            %correct column.
                            app.initResults{x,numbVars} = {feval(funcHandle, app.graphPeaksData, 0, 1.1, x, ind)};
                            app.graphPeaksData{x, 7} = extractMaxWavelenInten(app.WavelengthSingle.Value, app.graphPeaksData, x);
                        
                        % Between the range of entered wavelengths, select
                        % the maximum intensity of every peak at each time 
                        % interval (with a tolerance of +/- 0.05 seconds)
                        % so that the best absorbances can be selected.
                        elseif app.MultiwavelengthmonitorButton.Value == 1
                            minWavelength = app.WavelengthMultiLB.Value;
                            maxWavelength = app.WavelengthMultiUB.Value;
                            interval = app.graphPeaksData{x,3}(2) - app.graphPeaksData{x,3}(1); 
                            
                            %Extract the function handle based on the index
                            funcHandle = app.responceFunctions{2,1}{respFunc};
                            
                            %Evaluate the function and set the data in the results
                            %in the column that depends on numbVars so it is in the
                            %correct column.
                            app.initResults{x,numbVars} = {feval(funcHandle, app.graphPeaksData, 0, 1.1, x, minWavelength:interval:maxWavelength)};
                            app.graphPeaksData{x, 7} = extractMaxWavelenInten(minWavelength:interval:maxWavelength, app.graphPeaksData, x);
                            
                        elseif app.VWDmonitorButton.Value == 1
                            %Extract the function handle based on the index
                            funcHandle = app.responceFunctions{2,1}{respFunc};
                            
                            %Evaluate the function and set the data in the results
                            %in the column that depends on numbVars so it is in the
                            %correct column.
                            app.initResults{x,numbVars} = {feval(funcHandle, app.graphPeaksData, 0, 1.1, x, 1)};
                            app.graphPeaksData{x, 7} = extractMaxWavelenInten(app.VWDMonitorNumber.Value, app.graphPeaksData, x);
                        end
                    end
                    
                    % If an opt is running, try to evaluate the current
                    % experiemt, if not the try loop will fail and ignore
                    % it!
                    try 
                        % If analysis is only occuring at a predefined wavelength 
                        if app.SinglewavelengthmonitorButton.Value == 1
                            % Find the selected wavelength
                            ind = find(app.graphPeaksData{app.currExp,3}==app.WavelengthSingle.Value);
                            %Extract the function handle based on the index
                            funcHandle = app.responceFunctions{2,1}{respFunc};
                            %Evaluate the function and set the data in the results
                            %in the column that depends on numbVars so it is in the
                            %correct column.
                            app.initResults{app.currExp,numbVars} = {feval(funcHandle, app.graphPeaksData, 0, 1.1, app.currExp, ind)};
                            app.graphPeaksData{app.currExp, 7} = extractMaxWavelenInten(app.WavelengthSingle.Value, app.graphPeaksData, app.currExp);
                            
                        % Between the range of entered wavelengths, select
                        % the maximum intensity of every peak at each time 
                        % interval (with a tolerance of +/- 0.05 seconds)
                        % so that the best absorbances can be selected.
                        elseif app.MultiwavelengthmonitorButton.Value == 1
                            minWavelength = app.WavelengthMultiLB.Value;
                            maxWavelength = app.WavelengthMultiUB.Value;
                            interval = app.graphPeaksData{app.currExp,3}(2) - app.graphPeaksData{app.currExp,3}(1); 
                            
                            %Extract the function handle based on the index
                            funcHandle = app.responceFunctions{2,1}{respFunc};
                            
                            %Evaluate the function and set the data in the results
                            %in the column that depends on numbVars so it is in the
                            %correct column.
                            app.graphPeaksData{app.currExp, 7} = extractMaxWavelenInten(minWavelength:interval:maxWavelength, app.graphPeaksData, app.currExp);
                            app.initResults{app.currExp,numbVars} = {feval(funcHandle, app.graphPeaksData, 0, 1.1, app.currExp, minWavelength:interval:maxWavelength)};
                            
                        elseif app.VWDmonitorButton.Value == 1
                            %Extract the function handle based on the index
                            funcHandle = app.responceFunctions{2,1}{respFunc};
                            
                            %Evaluate the function and set the data in the results
                            %in the column that depends on numbVars so it is in the
                            %correct column.
                            app.initResults{app.currExp,numbVars} = {feval(funcHandle, app.graphPeaksData, 0, 1.1, app.currExp, 1)};
                            app.graphPeaksData{app.currExp,7} = extractMaxWavelenInten(app.VWDMonitorNumber.Value, app.graphPeaksData, app.currExp);
                        end


                    catch 
                        
                    end
                    
                    % Increment the number of vars by one if there are more
                    % functions to be evaluated
                    numbVars = numbVars + 1;
                end
            end
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function updateGraph(app)
            %Updates the graph UI depending on the chromatogram selected
            %and the mode selescted.
            
            % Gets the selected value that is contained in the list box
            selectedGraphNames = app.HPLCListBox.Value;
            
            % If there are multiple selected, it turns
            % them into a cell. Therefore this detects this and reverts it
            % back to a strign array if multiple are selected. If only one
            % is detected it is a char. Therefore convert it to a string.
            if iscell(selectedGraphNames)
                selectedGraphNames = cellfun(@string, selectedGraphNames);
            else
                selectedGraphNames = string(selectedGraphNames);
            end
        
            %Creates an intal empty logal array with all zero with all the
            %possible selections it could be
            logicArry = false(1,length(app.HPLCListBox.Items));
            
            %Loops through each responce function selectd and finds the
            %location of the responce function in the list of the allowed
            %functions. Then adds this onto the logcal array.
            for resp = 1:length(selectedGraphNames)
                
                % Finds the location of the responce function in the list 
                % of the allowed functions and flips it so it adds
                % correctly to the logic array.
                temp = strcmp(app.chromatogramLable, selectedGraphNames(resp))';
                
                % Adds on this location. If == 1 then that responce
                % function is being used, if == 0 then it is not.
                logicArry = logicArry + temp;
            end
            
            % Clear graph
            cla(app.GraphAxes,'reset')
            
            % If the 'Graph state' is in the chromatogram state
            if app.Switchgraphstate.Value == "1"
                
                % Reset var order when it is swtiched back and disable the
                % use of the var box
                app.varOrder = [];
                app.VarListBox.Enable = false;
                app.PlotparetodataCheckBox.Enable = false;
                
                % Start overlaying
                hold (app.GraphAxes, 'on');
                
                % loop through each element in the logical array and if it
                % is equal to one, it has been selected so plot it
                currGraph = 0;
                for ind = 1:length(logicArry)
                    if logicArry(ind) == 1
                            
                        currGraph = currGraph + 1;
                        
                        if app.VWDmonitorButton.Value == 1
                            wavelengthInd = 1;
                        else
                            wavelengthInd = find(app.graphPeaksData{ind,3}==app.WavelengthSpinner.Value);
                        end
                        
                        % Extracts the graph x and y data
                        x = app.graphPeaksData{ind,2}; % Time data
                        
                        if app.ThreeDSet.Value == 0
                            y = app.graphPeaksData{ind,4}(:,wavelengthInd); % Y coords of graph
                            
                            % Locations of each peak. peaks(x,1) is the peak number,
                            % peaks(x,2) is the x pos, peaks(x,3) is the y pos, peaks(x,4)
                            % is the full width and peaks(x,5) is the area, where x is the
                            % peak number.
                            peaks = app.graphPeaksData{ind,5}{:,wavelengthInd};
                           
                            plot(app.GraphAxes, x, y + 10*(currGraph-1), "LineWidth", 1.5, "Color",app.wongCM(currGraph,:))         
                            %plot(app.GraphAxes, x, y, "LineWidth", 1.5, "Color",app.wongCM(currGraph,:))
                            
                            xlabel(app.GraphAxes, "Time / minutes", "FontSize",18,"FontWeight","bold")
                            ylabel(app.GraphAxes, "Intensity", "FontSize",18,"FontWeight","bold")
                             xlim(app.GraphAxes,[0 app.TimemaxSpinner.Value])
                             ylim(app.GraphAxes,[app.YLowerSpinner.Value app.YUpperSpinner.Value])
    %                         a = get(app.GraphAxes,'XTickLabel');
    %                         set(app.GraphAxes,'XTickLabel',a,'fontsize',18,"FontWeight","bold")
    %                         a = get(app.GraphAxes,'YTickLabel');
    %                         set(app.GraphAxes,'YTickLabel',a,'fontsize',18,"FontWeight","bold")
                            %yticks(app.GraphAxes, [])
    
                            % Only if debug mode is switched on
                            if app.ToggledebugCheckBox.Value == 1
                                % Plot the 'tip' of each peak with a blue star
                                scatter(app.GraphAxes, peaks(:,2), peaks(:,3), "b*")
                                
                                % Plot the gaussian peaks based on the peaks data obtained.
                                % Loops through each peak in peaks array.
                                for currpeak = 1:size(peaks,1)
                                    % Generate a uniformly spaced array of x values based
                                    % on 4*width either side of the mean of the gaus
                                    % function
                                    gausX = linspace(peaks(currpeak,2) - (peaks(currpeak,4)*4), peaks(currpeak,2) + (peaks(currpeak,4)*4), 1000);
                                    
                                    % Use matlabs built in function to generate the gaus
                                    % plot, where gausX is the x coords, peaks(currpeak,4)/2.355
                                    % is the standard deviation (FWHM/2.355) and peaks(currpeak,2)
                                    % is the mean. peaks(currpeak,3) normalises the data to
                                    % the correct height.
                                    gausY = gaussmf(gausX,[peaks(currpeak,4)/2.355, peaks(currpeak,2)]).*peaks(currpeak,3);
                                    
                                    % Plot this gaus function and then repeat for all the
                                    % other peaks in the peaks array.
                                    plot(app.GraphAxes, gausX, gausY, "r--")
                                    
                                    % Puts the label number above the peak
                                    text(app.GraphAxes, peaks(currpeak,2), peaks(currpeak,3),string(peaks(currpeak,1)))
                                end
                            end
                            
                            if app.PlotBestIntensities.Value == 1
                                peaksIdeal = app.graphPeaksData{ind, 7};
                                % Plot the gaussian peaks based on the peaks data obtained.
                                % Loops through each peak in peaks array.
                                for currpeak = 1:size(peaksIdeal,1)
                                    % Generate a uniformly spaced array of x values based
                                    % on 4*width either side of the mean of the gaus
                                    % function
                                    gausX = linspace(peaksIdeal(currpeak,2) - (peaksIdeal(currpeak,4)*4), peaksIdeal(currpeak,2) + (peaksIdeal(currpeak,4)*4), 1000);
                                    
                                    % Use matlabs built in function to generate the gaus
                                    % plot, where gausX is the x coords, peaks(currpeak,4)/2.355
                                    % is the standard deviation (FWHM/2.355) and peaks(currpeak,2)
                                    % is the mean. peaks(currpeak,3) normalises the data to
                                    % the correct height.
                                    gausY = gaussmf(gausX,[peaksIdeal(currpeak,4)/2.355, peaksIdeal(currpeak,2)]).*peaksIdeal(currpeak,3);
                                    
                                    % Plot this gaus function and then repeat for all the
                                    % other peaks in the peaks array.
                                    plot(app.GraphAxes, gausX, gausY + 10*(currGraph-1), "r--")
                                    
                                    % Puts the label number above the peak
                                    text(app.GraphAxes, peaksIdeal(currpeak,2), peaksIdeal(currpeak,3)+ 10*(currGraph-1),string(peaksIdeal(currpeak,1) + ". " + peaksIdeal(currpeak,6)) + " nm")
                                end
                            end
                        elseif app.ThreeDSet.Value == 1
                             y = app.graphPeaksData{ind,3};
                             z = app.graphPeaksData{ind,4};
                             xAll = ones(length(x),length(y)).*x;
                             yAll = ones(length(x),length(y)).*y;
                             
                             surf(app.GraphAxes, xAll, yAll, z, "EdgeColor", "flat")
                             colormap(app.GraphAxes, "hot")
                             colorbar(app.GraphAxes)
                             xlabel(app.GraphAxes, "Time / minutes", "FontSize",18,"FontWeight","bold")
                             ylabel(app.GraphAxes, "Wavelength", "FontSize",18,"FontWeight","bold")
                             zlabel(app.GraphAxes, "Intensity", "FontSize",18,"FontWeight","bold")
                        end
                    end
                end
                
                % Stop overlaying
                hold (app.GraphAxes, 'off')
                
                % If there are greater than 2 chromoatograms being
                % displayed then display the legend
                if nnz(logicArry) >=2
                    % Selectedgraphnames is ordered the order it is clicked
                    % in. Therefore if 2 is clicked and then 1, the labels
                    % will be displayed in the order 2. and then 1., but
                    % the plotted graphs are displayed in numerical order
                    % 1. and then 2.. Therefore these selected graph names
                    % need to be sorted by numerical order.
                    
                    % Function by Stephen Cobeldick that sorts file names
                    % in order. Needs to be a cell of strings to perform
                    % the fucntion, then coverts back and sets the 'ordered' 
                    % names.
                    orderedNames = natsortfiles(cellstr(selectedGraphNames));
                    orderedNames = cellfun(@string, orderedNames);
                    %legend(app.GraphAxes,orderedNames);
                    legend(app.GraphAxes,orderedNames,"FontSize",18, "FontWeight","bold");
                end

            
            % If the graph state is in the 'desgin space' setting
            else
                
                % Allows the user to select what variables to look at in
                % the desin space
                app.VarListBox.Enable = true;
                app.PlotparetodataCheckBox.Enable = true;
                
                % Gets the selected value that is contained in the list box
                selectedVars = app.VarListBox.Value;
                
                % If there are multiple selected, it turns
                % them into a cell. Therefore this detects this and reverts it
                % back to a strign array if multiple are selected. If only one
                % is detected it is a char. Therefore convert it to a string.
                if iscell(selectedVars)
                    app.varOrder = cellfun(@string, app.VarListBox.Value);
                else
                    app.varOrder = string(app.VarListBox.Value);
                end
                 
                % If more than 1 variable is selected (so a 2D or more plot
                % can be done)
                if length(selectedVars) > 1
                    % If too many variables are selected and are added to varOrder
                    % then only select the first 5 (plot will only go as
                    % high as the 5th dimension)
                    if length(app.varOrder) > 5
                        app.varOrder = app.varOrder(1:5);
                    end
                    
                    % Combines the inital condtions data with the objective
                    % function results data (initResults). To make this work the inital
                    % condtions table is reduced to the same number of
                    % rows as the init results. As the elements in
                    % initResults are each stored as a cell, a cell2table
                    % function is used to convert the values to doubles,
                    % and the table is recreated.
                    combinedData = [app.initCondtions(1:size(app.initResults,1),:), cell2table(app.initResults{:,:}, "VariableNames", app.initResults.Properties.VariableNames)];
                    
                    % Logic array contains the true false data of each
                    % graph that is selected. Where 0 = not seleected and 1
                    % = selectd. This function converts this into a
                    % numbered array and removes any 0's so that it can be
                    % used to index the combined data table. For example if
                    % logicArry = [0 0 1 0 1 0] then it would be this .* [1
                    % 2 3 4 5 6] = [0 0 3 0 5 0] and then zeros removed to
                    % give [3 5] so the table is indexed at 3 and 5.
                    logicArry = logicArry.*(1:1:length(logicArry));
                    logicArry(logicArry==0) = [];
                    logicArry = logicArry'; % Makes sure it is in the right direction
                    
                    % Uses this logical array to index the specfic data
                    extractedData = combinedData(logicArry, app.varOrder);
                   
                     
                    % Plots a 2D graph when the number of columns in
                    % extracted data = 2;
                    if size(extractedData,2) == 2

                        % 2D Scatter graph
                        scatter(app.GraphAxes, extractedData{:,1},extractedData{:,2}, 20, 'black', 'filled')
                        
                        % As extractedData is a table it selects the VariableNames
                        % as the x and y label.
                        xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                        ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                        
                        % If debug mode is on, display the text
                        if app.ToggledebugCheckBox.Value == 1
                            text(app.GraphAxes, extractedData{:,1},extractedData{:,2}, " " + string(logicArry))
                        end
                        
                    % Plots a 3D graph when the number of columns in
                    % extracted data = 3;    
                    elseif size(extractedData,2) == 3
                        
                        % 3D scatter graph
                        scatter3(app.GraphAxes, extractedData{:,1}, extractedData{:,2}, extractedData{:,3}, 20, 'black', 'filled')
                        
                        grid(app.GraphAxes, "on")
                        
                        % Add to the same axis the lines that go down to
                        % the xy plane
                        hold (app.GraphAxes, 'on')
                        
                        % loop through each element and add the line that
                        % goes down to the xy plane
                        for x = 1:size(extractedData,1)
                            plot3(app.GraphAxes, [extractedData{x,1} extractedData{x,1}], [extractedData{x,2} extractedData{x,2}], [0 extractedData{x,3}], 'k--')
                        end
                        
                        % Stop appending to the same axis
                        hold (app.GraphAxes, 'off')
                        
                        % Add the graph labels based on the table col name
                        % values
                        xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                        ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                        zlabel(app.GraphAxes, extractedData.Properties.VariableNames(3));
                        
                        % if debug mode is on, then plot the numbers next
                        % each point for easier identifcation
                        if app.ToggledebugCheckBox.Value == 1
                            text(app.GraphAxes, extractedData{:,1},extractedData{:,2}, extractedData{:,3}, " " + string(logicArry))
                        end
                    
                    % Plots a 4D graph when the number of columns in
                    % extracted data = 4, using color as the 4th dimension 
                    elseif size(extractedData,2) == 4
                        
                        % 4D scatter graph where the colour is the 4th
                        % dimenson
                        scatter3(app.GraphAxes, extractedData{:,1}, extractedData{:,2}, extractedData{:,3}, 20, extractedData{:,4}, "filled")
                        
                        % Displays the gird so the vales are easier to read
                        grid(app.GraphAxes, "on");
                        
                        % Set the colur scheme to autumn
                        cmap = colormap(app.GraphAxes,autumn);
                        % Add the colour bar
                        cbar = colorbar(app.GraphAxes);
                        
                        % This sets up the colours for the dotted lines so
                        % that they match the scattered point colours. If
                        % all the values in extracted method are the same,
                        % then set it to the first color. If if is a
                        % difference between the values, use the max and
                        % min values to generate c, which is a matrix that
                        % spaces out the colours uniformly and in realtion
                        % to the colours of the scatter points.
                        if nnz(diff(extractedData{:,4})) == 0
                            c = ones(size(cmap,1));
                        else
                            c = round(1+(size(cmap,1)-1)*(extractedData{:,4} - min(extractedData{:,4}))/(max(extractedData{:,4})-min(extractedData{:,4})));
                        end
                        
                        % Add to the same axis the lines that go down to
                        % the xy plane
                        hold (app.GraphAxes, 'on');
                        
                        % Based on the index/ color psotion (c) plots a
                        % dotted line that goes down from the point to the
                        % xy plane
                        for x = 1:size(extractedData,1)
                            plot3(app.GraphAxes, [extractedData{x,1} extractedData{x,1}], [extractedData{x,2} extractedData{x,2}], [0 extractedData{x,3}], "Color",cmap(c(x),:), "LineStyle","--")
                        end
                        
                        % Stop appending to the same axis
                        hold (app.GraphAxes, 'off');
                        
                        % Add the graph labels based on the table col name
                        % values
                        xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                        ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                        zlabel(app.GraphAxes, extractedData.Properties.VariableNames(3));
                        
                        % Add title to colour bar
                        title(cbar, extractedData.Properties.VariableNames(4), "FontSize",10, "FontWeight","bold")
                        % Puts in a title so that the spacing at the top of
                        % the graph includes the title above the cbar
                        title(app.GraphAxes, " ", "FontSize",20, "FontWeight","bold")
                        cbar.FontSize = 10; %Makes font of cbar increments larger
                        
                        % if debug mode is on, then plot the numbers next
                        % each point for easier identifcation
                        if app.ToggledebugCheckBox.Value == 1
                            
                            % Loops through each point and adds text next
                            % to it that includes the number and var4 data
                            for graph = 1:size(extractedData,1)
                                text(app.GraphAxes, extractedData{graph,1},extractedData{graph,2}, extractedData{graph,3}, " " + string(logicArry(graph)) + ", " + string(extractedData{graph,4}))
                            end

                        end
                    
                    % Plots a 5D graph when the number of columns in
                    % extracted data = 5, using color as the 4th 
                    % dimension and size of the dot as the 5th dimension.
                    elseif size(extractedData,2) == 5
                        % Change these to vary the maximum and minimim dot
                        % sizes
                        maxDotSize = 100;
                        minDotSize = 5;
                        
                        % Scale the data so that the size is bewteen the
                        % max and min dot size, for when it is plotted on
                        % the graph.
                        scaledData = rescale(extractedData{:,5}, minDotSize, maxDotSize);
                        
                        % Generates 5 uniformly spaced marker sizes between
                        % the min and max dot size specified
                        ledgMarkerSize = linspace(minDotSize, maxDotSize, 5);
                        markersActualNumb = rescale(ledgMarkerSize, min(extractedData{:,5}), max(extractedData{:,5}));
                        
                        % Add to the same axis the lines that go down to
                        % the xy plane
                        hold (app.GraphAxes, 'on');
                        
                        % The loop starts to create the figger that
                        % displays the dot size. It works by plotting 5
                        % points of varying marker size (plot3 has to be
                        % used instead of scatter3 as only this changes the
                        % dot size in the legend for some reason) and
                        % setting their visabilty to 0. Then each each
                        % number that corrisponds to the 5 uniformly
                        % distrubuted sizes is set to a cell whoch then
                        % later is used to called the legend function
                        % (which only shows these first 5 dots). This has
                        % to be done before the scatter function as it
                        % needs to be the FIRST 5 dots.
                        for ind = 1:numel(ledgMarkerSize)
                           % Generate the plots and save them in figplots 
                           % array. Set them at (0,0,0) and set the marker
                           % size to the square root of the ledgMarkerSize
                           % (for some reason this works well for size
                           % comparson, not sure how acurate it is).
                           figplots(ind) = plot3(app.GraphAxes,0,0,0,'bo','markersize',round(sqrt(ledgMarkerSize(ind)),1),'MarkerFaceColor','blue', "MarkerEdgeColor","blue");
                           set(figplots(ind),'visible','off') % Make them invisable
                           
                           % Adds each number (acutal number not the marker
                           % size number) so that it is displayed as the
                           % label when the legend function is called later
                           legentry{ind} = num2str(round(markersActualNumb(ind),1));
                        end
                        
                        % 5D scatter graph where the colour is the 4th
                        % dimenson and size of the dot is the 5th dimension
                        scatter3(app.GraphAxes, extractedData{:,1}, extractedData{:,2}, extractedData{:,3}, scaledData, extractedData{:,4}, "filled")
                        
                        % Stop appending to the same axis
                        hold (app.GraphAxes, 'off');
                        
                        % Displays the gird so the vales are easier to read
                        grid(app.GraphAxes, "on")
                        
                        % Set the colur scheme to autumn
                        cmap = colormap(app.GraphAxes,autumn);
                        
                        % Add the colour bar
                        cbar = colorbar(app.GraphAxes);
                        
                        % This sets up the colours for the dotted lines so
                        % that they match the scattered point colours. If
                        % all the values in extracted method are the same,
                        % then set it to the first color. If if is a
                        % difference between the values, use the max and
                        % min values to generate c, which is a matrix that
                        % spaces out the colours uniformly and in realtion
                        % to the colours of the scatter points.
                        if nnz(diff(extractedData{:,4})) == 0
                            c = ones(size(cmap,1));
                        else
                            c = round(1+(size(cmap,1)-1)*(extractedData{:,4} - min(extractedData{:,4}))/(max(extractedData{:,4})-min(extractedData{:,4})));
                        end

                        % Add to the same axis the lines that go down to
                        % the xy plane
                        hold (app.GraphAxes, 'on');
                        
                        % Based on the index/ color psotion (c) plots a
                        % dotted line that goes down from the point to the
                        % xy plane
                        for x = 1:size(extractedData,1)
                            plot3(app.GraphAxes, [extractedData{x,1} extractedData{x,1}], [extractedData{x,2} extractedData{x,2}], [0 extractedData{x,3}], "Color",cmap(c(x),:), "LineStyle","--")
                        end
                        
                        % Stop appending to the same axis
                        hold (app.GraphAxes, 'off');
                        
                        % Add the graph labels based on the table col name
                        % values
                        xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                        ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                        zlabel(app.GraphAxes, extractedData.Properties.VariableNames(3));
                        
                        % Add title to colour bar
                        title(cbar, extractedData.Properties.VariableNames(4), "FontSize",10, "FontWeight","bold")
                        
                        % Puts in a title so that the spacing at the top of
                        % the graph includes the title above the cbar
                        title(app.GraphAxes, " ", "FontSize",20, "FontWeight","bold")
                        cbar.FontSize = 10; %Makes font of cbar increments larger
                        
                        % Creat the legend at the end after all points have
                        % been added as we only want the first 5 points
                        % (the dummy invisable ones stored in figplots)
                        leg = legend(app.GraphAxes, legentry);
                        leg.Location = 'eastoutside'; %Display next to the colour bar
                        leg.FontSize = 10; % Make font the same size as the color bar
                        % Gives the legend a title
                        title(leg, extractedData.Properties.VariableNames(5),"FontSize",10, "FontWeight","bold");
                        
                        %For some reason these actions set the graph state
                        %to 2D, so make sure it is 3D.
                        view(app.GraphAxes,3)

                        % if debug mode is on, then plot the numbers next
                        % each point for easier identifcation
                        if app.ToggledebugCheckBox.Value == 1
                            
                            % Loops through each point and adds text next
                            % to it that includes the number, var4 and 
                            % var5 data data
                            for graph = 1:size(extractedData,1)
                                text(app.GraphAxes, extractedData{graph,1},extractedData{graph,2}, extractedData{graph,3}, " " + string(logicArry(graph)) + ", " + string(extractedData{graph,4}) + ", " + string(extractedData{graph,5}))
                            end
                        end
                    end
                    
                    % If the user want to plot the pareto data. Warning
                    % this takes a little while to process.
                    if app.PlotparetodataCheckBox.Value == 1
                        
                        % Temp save of the method condtions as the
                        % functions use all of the current data to plot the
                        % paretio front.
                        tempResults = app.initResults;
                        tempCond = app.initCondtions;
                        
                        % Set the new results to the reduced data set that
                        % has been selected by the user
                        app.initResults = app.initResults(logicArry,:);
                        app.initCondtions = app.initCondtions(logicArry,:);
                        
                        % Obtains the LB/UB data that is sutiable to input
                        % into TSEMO
                        [usedVariables, usedResults, LB, UB, reducedData, ~] = app.checkVariables();
                        options = TSEMO_options();
                        
                        % This TS-EMO function has been modified just to 
                        % return pareto front, both actual and predicted.
                        funcOutput = cell(1,nargout(@TSEMOPareto));
                        [funcOutput{:}] = TSEMOPareto(usedVariables, usedResults, LB, UB,options);
                        
                        % Extracts the usefull data from the function
                        % output of the modified TS-EMO code (the acutal
                        % pareto and predicted data)
                        ActPareto = [funcOutput{1,1} funcOutput{1,2}];
                        PredPareto = [funcOutput{1,5} funcOutput{1,6}];
                        
                        % In the order the variables have been selected
                        % on the GUI (to be plotted), this codes finds the
                        % index of each data set being plotted so that it
                        % can be plotted correctly
                        selectedVars = [];
                        for x = 1:length(app.varOrder)
                            % Converts the variable names to a string and
                            % then finds the index of where the given
                            % varOrder string name is in the set of
                            % selected data.
                            selectedVars = [selectedVars, find(contains(cellfun(@string,reducedData.Properties.VariableNames),app.varOrder(x)))];
                        end
                        
                        % Given this order of data it is then extracted
                        % from the tables
                        ActParetoPlot = ActPareto(:,selectedVars);
                        PredParetoPlot = PredPareto(:,selectedVars);
                        
                        % Enable overlay
                        hold(app.GraphAxes, 'on')
                        
                        % If the data is two dimensonal then plot a 2D
                        % parito front
                        if size(ActParetoPlot,2) == 2
                            scatter(app.GraphAxes, ActParetoPlot(:,1),ActParetoPlot(:,2), "ro");
                            scatter(app.GraphAxes, PredParetoPlot(:,1), PredParetoPlot(:,2), "b*");
                        
                        % If the data is greater than 2 dimensons then just
                        % plot the first 3 dimensions of the pareto front
                        elseif size(ActParetoPlot,2) > 2
                            scatter3(app.GraphAxes,ActParetoPlot(:,1),ActParetoPlot(:,2),ActParetoPlot(:,3), "ro");
                            scatter3(app.GraphAxes, PredParetoPlot(:,1), PredParetoPlot(:,2), PredParetoPlot(:,3), "b*");
                        end
                        
                        % Stop overlay
                        hold(app.GraphAxes, 'off')
                        
                        % Reset the results back to what they were
                        app.initResults = tempResults;
                        app.initCondtions = tempCond;
                        
                    end
                    
                end
            end  
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  

        function [usedVariables, usedResults, LB, UB, tableForm, disData] = checkVariables(app)
            % Check if the reponce function values are not all the
            % same, and exclude any from the opt if they are (if/when
            % it is then vaired the opt will change to include it)
            
            % Looks at the objective function results from the prevous
            % experiments. If there are differences in the data (the
            % responce function HAS been changing when the external
            % factors have been changed) accept it and store it in used
            % results. If not exculde it as it will produce and error
            % if it is fed into the opt algorithm
            usedResults = [];
            table1 = [];
            if ~isempty(app.initResults)
            
                objFuncDiff = zeros(1,3);
                for x = 1:size(cell2mat(app.initResults{:,:}),2)
                    if length(unique(cell2mat(app.initResults{:,x}))) > 1
                        objFuncDiff(x) = 1;
                    else
                        objFuncDiff(x) = 0;
                    end
                end
                
                % Finds the algorithms that have been ACTUALLY selected to
                % prevent them from all being input into the algorithm
                for x = 1:length(string(app.respSelected))
                    indResp(x) = find(strcmp(app.responceFunctions{1, 1}, string(app.respSelected(x))));
                end
                selectedAlgs = zeros(1,length(app.responceFunctions{1, 1}));
                selectedAlgs(1,indResp) = 1;
                selectedAlgs = logical(selectedAlgs);
                
                %Combine those that are changing AND those that have been
                %selected to give the actual opt algorithm data to pass
                objFuncDiff = logical(objFuncDiff) & selectedAlgs;
                usedResults = app.initResults(:,objFuncDiff);
                table1 = usedResults;
                usedResults = cell2mat(usedResults{:,:});
            end
            
            % Looks at the variables from the prevous
            % experiments. is not If there are differences in the data (the
            % variables have changed) accept it and store it in used
            % variables. If not exculde it as it a condtion that
            % is being varied.
            variablesDiff = ~all(app.initCondtions{:,:} == app.initCondtions{1,:});
            usedVariables = app.initCondtions(:,variablesDiff);
            table2 = usedVariables;
            usedVariables = usedVariables{:,:};
            
            %Convert the discrete variables (if any) into ordered numbers
            %instead of the set numbers. E.g. if discrete values 2 and 4
            %are used, turn these into 1 and 2 to give to the algorithm and
            %then change back after so that the algorithm works.
            
            
            % Finds the index of the discrete data being optimised (if
            % there is any)
            index = find((app.variables.DiscreteOrContinuous == 'D') & (app.variables.Optimise == 1))';
            levels = []; % predefines the levels of the data
            
            % if the index is not empty (therefore there are discrete
            % variables being optimised)
            if ~isempty(index)
                  
                % Loop through each index and add the length (the number of
                % different levels) of the discrete array to 'levels'.
                for ind = 1:length(index)
                    levels = [levels, (cellfun(@(x) size(x, 2), app.variables.Data(index(ind))))];
                end
                
                % Changes the index so that it is the last 'however many'
                % variables in the data set that are descrete
                newindex = size(usedVariables,2) - (length(index) - 1) :size(usedVariables,2);
                % Combine this index data and levels info into one array to
                % return this data
                disData = [newindex;levels];
                
                % Loop through each discrete variable
                for x = 1:size(app.variables.Data(index),1)
                    % The differnt possible values for that discrete var
                    discretVars = app.variables.Data{index(x)};
                    % Numbers 1 to the number of seleted discrete
                    % factors that will replace the vars above
                    replaceNumbs = 1:length(app.variables.Data{index(x)});
                    
                    % Loops through each possible value in the discrete var
                    % and replaces it with the new ordered list.
                    for disVar = 1:length(app.variables.Data{index(x)})
                        % Obtains the logical array to find all the
                        % elements that contain the numbers being replaced.
                        % Then uses this array to replace the number with
                        % the new orderd list before moving onto the next
                        % one. E.g. if 3 5 is being replaced with 1 2, the
                        % first iteration will find the index where all the
                        % 3's are and then the second line will replace
                        % them with 1. Then next loop will then find the
                        % location of all the 5's and replace them with 2.
                        logicArry = (usedVariables(:,newindex(x)) == discretVars(disVar));
                        usedVariables(logicArry,newindex(x)) = replaceNumbs(disVar);
                    end
                end
                
            else
                % If empty then return an empty matrix
                disData = [];
            end
            
            % This section of code extracts the upper and lower boundary
            % for each continous variable looked at.
            contLog = (app.variables.DiscreteOrContinuous == 'C') & (app.variables.Optimise == 1);
            contUBLB = cell2mat(app.variables.Data(contLog));
            
            % If the data contains discrete variables, this section sets
            % the upper and lower bounds sutiable for the algorithm. So if
            % discrete vars 2,4 and 5 are selected the LB is set
            % to 1 and the UB is set too 3 (as there are 3 dis differences
            % in total).
            if ~isempty(disData)
                
                disUBLB = []; % predefine
                % Loop through each discrete var in the disData matrix 
                % defined ealier
                for x = 1:size(disData,2)
                    % Create the new upper and lower bound based on the max
                    % number of levels detected. (the second row is the
                    % number of levels/different discrete var options).
                    disUBLB = [disUBLB; 1 disData(2,x)];
                end
                
                % Concatinate both the continous and discrete data to be
                % returned
                allUBLB = [contUBLB;disUBLB];
            else
                % If no discrete vars, then just return the 
                allUBLB = contUBLB;
            end
            
            LB = allUBLB(:,1)'; % Extracts the LB data
            UB = allUBLB(:,2)'; % Extracts the UB data 
            tableForm = [table2 table1];
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % Writes the experiment to the text box given the experiments to
        % write. exps = [1:app.currExp] if you want all of them or you
        % could set exps = [2 5 8 etc.] for any experiment you want.

        function writeCurrExperiment(app, exps, log)
            if nargin == 2
                log = false;
            end
            
            % Concatinates the name of the headers for the inital condtions 
            % and the responce factors selected.
            headers =  [app.initCondtions.Properties.VariableNames app.initResults.Properties.VariableNames];
            % Generates a numbered list from 1 to the number of experiments
            % to inform the user which experiment belongs to which.
            % 'Exp.' is the heading of this column.
            experiments = [{'Exp.'} cellstr(string(exps))];
            % Convert the results and condtions into the correct format cell 
            % matrix of characters: this is the format it needs to be in 
            % for the 'print_table' function. "'UniformOutput', false" is 
            % needed to do this.
            resultsTemp = cellfun(@string, table2cell(app.initResults), 'UniformOutput', false);
            condtionsTemp = cellfun(@string, table2cell(app.initCondtions(exps,:)), 'UniformOutput', false);
            
            % This loop adds 'N/A' to the results matrix if the experiment
            % has not been run yet. This is only needed if the current 
            % experiment number is less than the number of inital 
            % experiments generated.

            % Predefine temp empty cell
            newResults = cell(length(exps), length(app.initResults.Properties.VariableNames));
            % Loops through each inital result
            for x = exps
                % If the current results have been generated (which
                % would be contained in 'resultsTemp') then try to add
                % these condtions to the cell. If it can't add it (it
                % does not exist), 'catch' will replace it with
                % 'N/A' noting the experiment has not been run yet.
                try 
                    newResults(x,:) = resultsTemp(x,:);
                    
                    % if cell row is actully empty then replace it with
                    % 'N/A'. Just checks the first row because if one
                    % is empty, all of them will be.
                    if isempty(resultsTemp{x,1})
                        newResults(x,:) = {'N/A'};
                    end
                catch
                    newResults(x,:) = {'N/A'};
                end
            end
            
            % Updates results with the extracted ones. If selected cells is
            % used there will be gaps in the table. This code removes these
            % empty cell rows.
            resultsTemp = newResults(~cellfun('isempty', newResults(:,1)), :);
            
            % Generates the neatly formated table for display
            tableText = newline + string(print_table([condtionsTemp resultsTemp] , {'%s'}, headers, experiments','printBorder',1));
            % Updates the textbox with the formated data
            app.updateTextBox(true, tableText, log);
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        % Changes the cell in the excel document that tells the HPLC to shutdown
        % and then executes the mouse clicker to initiate the shutdown.

        function error = shutdownHPLC(app)
            
            try
                % Establish conectin with Excel server
                excelapp = actxGetRunningServer('Excel.Application');
                wkbk = excelapp.Workbooks;
                list = fieldnames(excelapp);
                sheets = excelapp.Sheets;
                sheet12 = Item(sheets,1);
                
                set(sheet12,'Range','I2', 0); % Tell HPLC to stop in excel
                SetAndRunHPLC % Execute the marco to tell it to stop
                
                % Inform user
                app.updateTextBox(true, "Atempting to shutdown HPLC by writing to excel", true)
                error = false;
            
            catch
                
                % Informs user that the HPLC failed to shutdown.
                app.updateTextBox(true, newline + "Failed to write to HPLC to switch off, please do it manually." + newline)
                app.logTrack.error('StartInitButtonPushed', 'Failed to write to excel to switch HPLC off')
                error = true;
                
                % ADD HERE EMAIL SYSTEM TO NOTFIY USER AN ERROR HAS OCCURED
                % WITH SHUTDOWN
                
            end
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% Changes the cell in the excel document that tells the HPLC to start
% and then executes the mouse clicker to initiate the setup.

        function error = startHPLC(app)
            try
                % Establish conectin with Excel server
                excelapp = actxGetRunningServer('Excel.Application');
                wkbk = excelapp.Workbooks;
                list = fieldnames(excelapp);
                sheets = excelapp.Sheets;
                sheet12 = Item(sheets,1);
                set(sheet12,'Range','I2', 1); % Tell HPLC to start in excel
                
                app.updateTextBox(true, newline + "HPLC started" + newline + newline, true)
                error = false;
            
            catch
                app.updateTextBox(true, newline + "Could not connect to excel server to start HPLC." + newline)
                app.logTrack.error('startHPLC', 'Failed to connect to excel to start HPLC.')
                error = true;
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% Gets the file name of the HPLC just run for processing the data in the
% excel document.

%%PFIZER EDIT HERE
%This obtains the file location for the HPLC data (both the
%chromatogram data and the peaks data)

        function [error,extension] = getfilenameHPLC(app)
            try
                % Establish conectin with Excel server
                excelapp = actxGetRunningServer('Excel.Application');
                wkbk = excelapp.Workbooks;
                list = fieldnames(excelapp);
                sheets = excelapp.Sheets;
                sheet12 = Item(sheets,1);
                
                %extension = get(sheet12,'Range','I5').value; % Get the file name
                extension = get(sheet12,'Range','I5');
                extension = extension.Value;
                error = false;
            catch
                
                app.updateTextBox(true, newline + "Failed to write updates to excel server." + newline)
                app.logTrack.error('StartInitButtonPushed', 'RunCondions failed to write to excel file.')
                error = true;
                
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
function changeEnableState(app, logicArry)
            app.ConfigvarButton.Enable = logicArry(1);
            app.SamplePositionEditField.Enable = logicArry(2);
            app.InitexpSpinner.Enable = logicArry(3);
            app.EqulibrationtimeSpinner.Enable = logicArry(4);
            app.HPLCDirectoryButton.Enable = logicArry(5);
            app.ObjectivefunctionsListBox.Enable = logicArry(6);
            app.AlgorithmDropDown.Enable = logicArry(7);
            app.DatafilenameEditField.Enable = logicArry(8);
            app.LoadVarsButton.Enable = logicArry(9);
            app.ApplyVarsButton.Enable = logicArry(10);
            app.ReprocessdataButton.Enable = logicArry(11);
            app.ResetButton.Enable = logicArry(12);
            app.StartInitButton.Enable = logicArry(13);
            app.PauseInitButton.Enable = logicArry(14);
            app.StartOptButton.Enable = logicArry(15);
            app.PauseOptButton.Enable = logicArry(16);
            app.HPLCListBox.Enable = logicArry(17);
            app.ToggledebugCheckBox.Enable = logicArry(18);
            app.Switchgraphstate.Enable = logicArry(19);
            app.WavelengthSpinner.Enable = logicArry(20);
            app.PlotparetodataCheckBox.Enable = logicArry(21);
            app.VarListBox.Enable = logicArry(22);
            
        end
        
        function normiliseAllIntensities(app)
            
            % Subtract all the different wavlengths recorded by the first
            % so that it starts at 0
            for chromatogram = 1:size(app.graphPeaksData,1)
                %app.graphPeaksData(chromatogram, 4) =  {app.graphPeaksData{chromatogram,6} - min(app.graphPeaksData{chromatogram,6})};
                app.graphPeaksData(chromatogram, 4) =  {app.graphPeaksData{chromatogram,6} - app.graphPeaksData{chromatogram,6}(1,:)};
                %app.graphPeaksData(chromatogram, 4) =  app.graphPeaksData(chromatogram,6);
                
                % Subtracts a reference wavelength if desired
                if app.RefwavelengthSpinner.Value > 0
                    app.graphPeaksData(chromatogram, 4) = {app.graphPeaksData{chromatogram, 4} - app.graphPeaksData{chromatogram,6}(:,app.RefwavelengthSpinner.Value)};
                end
                
                
                %app.graphPeaksData(chromatogram, 4) =  {app.graphPeaksData{chromatogram,6}};
                maxValues(chromatogram,:) = max(app.graphPeaksData{chromatogram,6});
                minValues(chromatogram,:) = min(app.graphPeaksData{chromatogram,6});
            end
            
%             maxValue = max(maxValues, [], 1);
%             minValue = min(minValues, [], 1);
             %Scale by the maximum and minimum overall absorbance recorded.
             maxValue = max(maxValues, [], "all");
             minValue = min(minValues, [], "all");
        
            % Scale each chromtogram between 0 and 200
            for chromatogram = 1:size(app.graphPeaksData,1)
                app.graphPeaksData{chromatogram, 4} = (app.graphPeaksData{chromatogram, 4}./(maxValue - minValue))*200;
                
                if app.VWDmonitorButton.Value == 1
                    if app.BuiltindetectionButton.Value == 1
                        peaks = {autofindpeaks(app.graphPeaksData{chromatogram,2},app.graphPeaksData{chromatogram,4},app.slopeThresh,app.ampThresh,app.smoothWidth,app.peakGroup,3)};
                    else
                        %PFIZER EDIT HERE (readMethodTXTFile.m)
                        %You could ignore this bit and just make sure the
                        %"built in detection" is always used. This was the
                        %way i was able to extract the peak infomation
                        %from a CSV file that chemstation was able to
                        %extract automatically after the method had
                        %finished. The file will need to be edited as
                        %Empower will have a different format or if using
                        %the API it will be a different call to extract the
                        %peaks data.
                        
                        filename = app.hplcDirectory  + "\" + app.graphPeaksData{chromatogram, 1} + "\Report.Txt";
                        peaks = {readMethodTXTFile(filename,app.VWDMonitorNumber.Value, app.AreacutoffEditField.Value, app.HeightcutoffEditField.Value, [minValue maxValue])};

                        %%
                    end
                else
                % Pick the peaks
                    for x = 1:length(app.graphPeaksData{chromatogram,3})
                        % Uses a peak finding algorithm written from
                        % "https://terpconnect.umd.edu/~toh/spectrum/PeakFindingandMeasurement.htm"
                        % to find the location of the peaks, where (x,y,SlopeThreshold,AmpThreshold,smoothwidth,peakgroup,smoothtype)
                        peaks(x) = {autofindpeaks(app.graphPeaksData{chromatogram,2},app.graphPeaksData{chromatogram,4}(:, x),app.slopeThresh,app.ampThresh,app.smoothWidth,app.peakGroup,3)};
                    end
                end
                app.graphPeaksData{chromatogram,5} = peaks;
            end
        end
        
        function saveSettings(app)
            
            app.Settings.hplcDirectory = app.HPLCDirectoryEditField.Value;
            app.hplcDirectory = app.HPLCDirectoryEditField.Value;

            app.Settings.initExpNumb = app.InitexpSpinner.Value;
            app.initExpNumb = app.InitexpSpinner.Value;

            app.Settings.equalTime = app.EqulibrationtimeSpinner.Value;
            app.equalTime = app.EqulibrationtimeSpinner.Value;

            app.Settings.algSelected = app.AlgorithmDropDown.Value;
            app.algSelected = app.AlgorithmDropDown.Value;

            app.Settings.respSelected = app.ObjectivefunctionsListBox.Value;
            app.respSelected = app.ObjectivefunctionsListBox.Value;

            app.Settings.refWavelength = app.RefwavelengthSpinner.Value;

            app.Settings.samplePos = app.SamplePositionEditField.Value;
            app.samplePos = app.SamplePositionEditField.Value;

            app.Settings.calcAllRespFunc = app.CalcuateallresponcefunctionsCheckBox.Value;
            app.Settings.SinglewavelengthmonitorButton = app.SinglewavelengthmonitorButton.Value;
            app.Settings.WavelengthSingle = app.WavelengthSingle.Value;
            app.Settings.MultiwavelengthmonitorButton = app.MultiwavelengthmonitorButton.Value;
            app.Settings.WavelengthMultiLB = app.WavelengthMultiLB.Value;
            app.Settings.WavelengthMultiUB = app.WavelengthMultiUB.Value;
            app.Settings.respSelected = app.ObjectivefunctionsListBox.Value;
            app.Settings.VWDMonitorNumber = app.VWDMonitorNumber.Value;
            app.Settings.VWDmonitorButton = app.VWDmonitorButton.Value;
            
            app.Settings.BuiltindetectionButton = app.BuiltindetectionButton.Value;
            app.Settings.ChemstationdetectionButton = app.ChemstationdetectionButton.Value;
            app.Settings.HeightcutoffEditField = app.HeightcutoffEditField.Value;
            app.Settings.AreacutoffEditField = app.AreacutoffEditField.Value;

            app.ampThresh = app.AmpthresholdEditField.Value;
            app.Settings.ampThresh = app.AmpthresholdEditField.Value;

            app.slopeThresh = app.SlopethresholdEditField.Value;
            app.Settings.slopeThresh = app.SlopethresholdEditField.Value;

            app.smoothWidth = app.SmoothwidthEditField.Value;
            app.Settings.smoothWidth = app.SmoothwidthEditField.Value;

            app.peakGroup = app.PeakgroupEditField.Value;
            app.Settings.peakGroup = app.PeakgroupEditField.Value;
        end


        function loadSettings(app, vars)
            
            % Try loading the Settings file, if it does not exist then just
            % keep the defaults.
            try
                app.Settings = vars{8,2};

                try 
                    app.hplcDirectory = app.Settings.hplcDirectory;
                    app.HPLCDirectoryEditField.Value = app.Settings.hplcDirectory;

                    app.initExpNumb = app.Settings.initExpNumb;
                    app.InitexpSpinner.Value = app.Settings.initExpNumb;

                    app.equalTime = app.Settings.equalTime;
                    app.EqulibrationtimeSpinner.Value = app.Settings.equalTime;

                    app.algSelected = app.Settings.algSelected;
                    app.AlgorithmDropDown.Value = app.Settings.algSelected;

                    app.respSelected = app.Settings.respSelected;
                    app.ObjectivefunctionsListBox.Value = app.Settings.respSelected;

                    app.RefwavelengthSpinner.Value = app.Settings.refWavelength;

                    app.samplePos = app.Settings.samplePos;
                    app.SamplePositionEditField.Value = app.Settings.samplePos;

                    app.CalcuateallresponcefunctionsCheckBox.Value = app.Settings.calcAllRespFunc;

                    app.SinglewavelengthmonitorButton.Value = app.Settings.SinglewavelengthmonitorButton;

                    app.WavelengthSingle.Value = app.Settings.WavelengthSingle;

                    app.MultiwavelengthmonitorButton.Value = app.Settings.MultiwavelengthmonitorButton;

                    app.WavelengthMultiLB.Value = app.Settings.WavelengthMultiLB;

                    app.WavelengthMultiUB.Value = app.Settings.WavelengthMultiUB;
                    
                    app.VWDMonitorNumber.Value = app.Settings.VWDMonitorNumber;
                    
                    app.VWDmonitorButton.Value = app.Settings.VWDmonitorButton;
                    
                    app.BuiltindetectionButton.Value = app.Settings.BuiltindetectionButton;
                    app.ChemstationdetectionButton.Value = app.Settings.ChemstationdetectionButton;
                    app.HeightcutoffEditField.Value = app.Settings.HeightcutoffEditField;
                    app.AreacutoffEditField.Value = app.Settings.AreacutoffEditField;

                    app.ampThresh = app.Settings.ampThresh;
                    app.AmpthresholdEditField.Value = app.Settings.ampThresh;

                    app.slopeThresh = app.Settings.slopeThresh;
                    app.SlopethresholdEditField.Value = app.Settings.slopeThresh;
        
                    app.smoothWidth = app.Settings.smoothWidth;
                    app.SmoothwidthEditField.Value = app.Settings.smoothWidth;
        
                    app.peakGroup = app.Settings.peakGroup;
                    app.PeakgroupEditField.Value = app.Settings.peakGroup;

                catch
                     app.updateTextBox(true, newline + "WARNING. One or more settings failed to load.")
                end

            catch

                app.updateTextBox(true, newline + "Failed to load settings. Saving default conditions as new ones.")
                app.saveSettings();

            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Set the different algoithms and reference to their function
            % handles
            app.algorithms{1,:} = ["Snobfit", "LVBayesian", "TS-EMO", "MVMOO", "BayesianOptimiser"];
            app.algorithms{2,:} = {@snobfit, @LVBayesian, @TSEMO_V4Cond, @MVMOO, @BayesianOpt};
            app.algorithms{3,:} = {@optionsSnob, @optionsLVBayesian, @TSEMO_options, @optionsMVMOO, @optionsBayesOpt};
            
            % Set the responce functions and reference to their function
            % handles
            app.responceFunctions{1,:} = ["RsCrit2", "Maximisepeaknumber", "Minimisemethodtime", "CombinedFunction1", "WeightRespcmtmdHPLC018"];
            app.responceFunctions{2,:} = {@responceRscrit, @responceNumbPeaks, @responceMaxTime, @responceCombFunc1, @WeightRespcmtmdHPLC018};
            
            % Updates the responce function drop down so that it contains
            % the names stated above
            respNames = app.responceFunctions{1,:}(:);
            app.ObjectivefunctionsListBox.Items = respNames;
            app.AlgorithmDropDown.Items = app.algorithms{1,:}(:);
            app.AlgorithmDropDown.Value = app.algorithms{1,:}(1);

            % Update algorithm box in simulate
            modelsForSimulate = ["constant", "linear", "interactions", "purequadratic", "quadratic", "interp"];
            app.ModeltofitDropDown.Items = modelsForSimulate;
            app.ModeltofitDropDown.Value = modelsForSimulate(1);

            app.testFunctions{1,:} = ["None","peaks","ackley","beale","bird","booth","bukin4","bukin6","carromtable","chichinadze","crossfunc","crossintray","crosslegtable","crownedcross","cube","easom","eggholder","giunta","goldsteinprice","griewank","helicalvalley","himmelblau","holdertable","leon","levi13","matyas","mccormick","modschaffer1","modschaffer2","modschaffer3","modschaffer4","penholder","powell","rastrigin","rosenbrock","schweffel","sinenvsin","sixhumpcamel","styblinskitang","testtubeholder","threehumpcamel","trigonometric","wood","zettl"];
            app.testFunctions{2,:} = {[] , @peaks , @ackley , @beale , @bird , @booth , @bukin4 , @bukin6 , @carromtable , @chichinadze , @crossfunc , @crossintray , @crosslegtable , @crownedcross , @cube , @easom , @eggholder , @giunta , @goldsteinprice , @griewank , @helicalvalley , @himmelblau , @holdertable , @leon , @levi13 , @matyas , @mccormick , @modschaffer1 , @modschaffer2 , @modschaffer3 , @modschaffer4 , @penholder , @powell , @rastrigin , @rosenbrock , @schweffel , @sinenvsin , @sixhumpcamel , @styblinskitang , @testtubeholder , @threehumpcamel , @trigonometric , @wood , @zettl};
            app.TestfunctionsDropDown.Items = app.testFunctions{1,:}(:);
            
            % Update algorithm box in simulator
            app.OptalgorithmDropDown.Items = app.algorithms{1,:}(:);
            app.OptalgorithmDropDown.Value = app.algorithms{1,:}(1);
            
            % Updates the alg names so that the drop down contains the
            % names stated above
            algNames = app.algorithms{1,:}(:);
            app.AlgorithmDropDown.Items = algNames;
            app.AlgorithmDropDown.Value = algNames(3); 
            app.updateRespBox();
            
            %Colour Blind Friendly colourmap
            app.wongCM = [230,159,0;        %Orange
                          86, 180, 233;     %Blue
                          0,158,115;        %Green
                          240,228,66;       %Yellow
                          0,114,178;        %Dark blue
                          213,94,0;         %Dark orange
                          204,121,167;      %Pink
                          0,0,0]./255;      %Black

        end

        % Button pushed function: ApplyVarsButton
        function ApplyVarsButtonPushed(app, event)
            % Looks at the file name entered and adds the '.mat'
            % CHANGE FOLDER SAVE IN FUTURE TO CONTAIN WHOLE DIRC NAME!
            app.folderSave = app.ResultsDir + app.DatafilenameEditField.Value;
            app.fileNameSave = app.DatafilenameEditField.Value + ".mat";
            filename = app.folderSave + "\" + app.fileNameSave;
            
            % if the file already exists, promts the user with a pop up box
            % to notify them that they will be overwriting a file if they
            % proceeed with this.
            if isfile(filename)
                % Code for the pop up box to warn the user that it will change
                % the responce function values. If no is selected nothing
                % happens. Look below for yes.
                selection = uiconfirm(app.HPLC, "Are you sure you want to " + ...
                    "apply these new settings? This will overwrite the current file." + ...
                    "functions.", "Reprocess", "Icon","warning","Options", ...
                    ["Yes", "No"], "DefaultOption", 2, "CancelOption",2);
                
                % if selection is yes, then overwrite the file
                if selection == "Yes"
                    app.applyVars()
                end
            
            % If it does not already exist, then create it anyway
            else
                mkdir(app.folderSave);
                app.applyVars()
            end
        end

        % Button pushed function: StartInitButton
        function StartInitButtonPushed(app, event)
            
            % Runs the script that starts the HPLC using the mouseclicker.
            % If this fails an error is displayed and the function is
            % terminated.

            %%PFIZER EDIT HERE
            %Establish connection with HPLC, switch it on etc. Just a
            %general check to make sure the instrument is good to go!
            error = app.startHPLC();
            if error == true
                return
            end
            %%
            
            app.updateTextBox(true, newline + "***INITIAL CONDTIONS STARTED***" + newline)
            
            % Disable buttons
            app.LoadVarsButton.Enable = false;
            app.ResetButton.Enable = false;
            app.ApplyVarsButton.Enable = false;
            app.StartInitButton.Enable = false;
            app.ReprocessdataButton.Enable = false;
            app.PauseInitButton.Enable = true;
            app.InitLamp.Color = [0 1 0];
            app.paused = false;
                
            % Start while loop that executes each experiment that has been
            % scheduled by the LHS. 
            
            while app.currExp <= app.initExpNumb && app.paused == false
                
                % Notfify the user the experiment has started using the
                % clock
                c = clock;
                time = string(c(4)) + ":" + string(c(5)); % Converts the time to the correct format
                app.updateTextBox(true, "Experiment " + string(app.currExp) + " started at " + time + newline, true)
                app.updateTextBox(true, "Condtions: " + newline, true)
                
                % Write the current conditions that are to be run.
                app.writeCurrExperiment(app.currExp, true)
                
                app.updateTextBox(true, "Waiting for method to finish..." + newline + newline, true)
                
                % Attempt to run the condtion that has been set.
                try
                    % Run  condtion that has been set depending on the
                    % current experiment

                    %%PFIZER EDIT HERE
                    %This calls the function(s) to write the method to the
                    %instrument. If it fails it will reach the "timeout"
                    %and the app will return an error
                    timeout = RunCond(app.initCondtions, app.currExp, app.equalTime, app.errorTime);
                    %%

                    if timeout == true
                        app.updateTextBox(true, newline + "Experiment took longer than " + string(app.errorTime) + " minutes to finish so aborted." + newline)
                        app.logTrack.error('StartInitButtonPushed', 'Experiment took too long to run (' + string(app.errorTime) + ' minutes)')
                        return
                    end
                    
                    % Notify the user the time the method finished at.
                    c = clock;
                    time = string(c(4)) + ":" + string(c(5));
                    app.updateTextBox(true, "Method finished at " + time + newline, true)
                
               catch % If this fails notfiy the user and terminate the function
                    app.updateTextBox(true, newline + "Failed to run the inital condtions. Ending the inital condtions." + newline)
                    app.logTrack.error('StartInitButtonPushed', 'RunCondions failed to run an experiment.')
                    return
                    
                end
                
                % Get the file name from the excel file. If this fails an 
                % error is displayed and the function is terminated.
    
                %%PFIZER EDIT HERE
                %This obtains the file location for the HPLC data (both the
                %chromatogram data and the peaks data). May not be neeed
                %with the API now.
                [error, extension] = app.getfilenameHPLC();
                %%
                if error == true
                    return
                end
                
                % Attempt to read the HPLC file
                try
                    app.updateTextBox(true, "Reading method file ..." + newline + newline, true)
                    
                    % Read and process the data file
                    % Imports the data using importAgilent function, created by ... and
                    % tidies the data, as well as importing it into a table
    
                    %%PFIZER EDIT HERE
                    %This extracts all the chromatogram data including: the
                    %time intensity data for each channel set up on the
                    %method and the DAD detector data if set up for the
                    %190-400 nm spectra

                    data = importAgilent2("file", app.hplcDirectory + "\" + extension, 'verbose', 'off');

                    %%
                    if app.VWDmonitorButton.Value == 1
                        data = data(app.VWDMonitorNumber.Value + 1,:);
                        wavelength = data.channel_units;
                    else
                        data = data(1,:);
                        wavelength = data.channel; % Wavelengths
                    end
                    
                    time = data.time; % Time data
                    intensity = data.intensity;

                    % Save the data in the graphs and Peaks cell which then
                    % later gets saved to the current file
                    app.graphPeaksData{app.currExp,1} = extension;
                    app.graphPeaksData{app.currExp,2} = time;
                    app.graphPeaksData{app.currExp,3} = wavelength;
                    app.graphPeaksData{app.currExp,4} = intensity; % modified intensity
                    app.graphPeaksData{app.currExp,6} = intensity; % Unmodified intensity
                    
                    % Normalise the intensity at each wavelength depending
                    % on the maximum and minimum values of all the
                    % chromatograms (into the 4th column of graphPeaksData)
                    % and pick the peaks

                    %%PFIZER EDIT HERE
                    %Part of this function needs attention (see inside)
                    normiliseAllIntensities(app);   
                    
                catch % If this fails notfiy the user and terminate the function
                    
                    app.updateTextBox(true, newline + "Failed to read HPLC file" + newline)
                    app.logTrack.error('StartInitButtonPushed', 'Failed to read HPLC file.')
                    return
                end
                
                % Attempt to calculate the responce variables from the
                % results.
                try
                    app.updateTextBox(true, "Calculating responce variables ..." + newline + newline, true)
                    app.CalcResponces(); 
                    app.updateTextBox(true, "Responce vars generated. Experiment " + string(app.currExp) + " finished. Results: " + newline, true)
                    
                catch % If this fails notfiy the user and terminate the function
                    
                    app.updateTextBox(true, newline + "Failed to calculate responce variables" + newline)
                    app.logTrack.error('StartInitButtonPushed', 'Failed to calculate responce variables.')
                    return
                end
                    
                % Update the graph box with the new data
                app.UpdateGraphList();
                
                % Increment the current experiment number
                app.currExp = app.currExp + 1;
                
                % Save the data to the file before running the next 
                app.saveVarFile();
                
                % Write the updated condtions with the responces
                app.writeCurrExperiment(app.currExp-1, true)
                
            end
            
            % Display time when the condtions finished
            c = clock;
            time = string(c(4)) + ":" + string(c(5));
            app.updateTextBox(true, "***INIT CONDTIONS FINISHED AT " + time + "***", true)
            
            % Reconfiger buttons
            app.StartInitButton.Enable = true;
            app.PauseInitButton.Enable = false;
            app.ResetButton.Enable = true;
            app.ReprocessdataButton.Enable = true;
            app.InitLamp.Color = [1 0 0];
            
            
            % If the inital number of experiments run has been reached
            % (meaning it is time to start optimising!)
            if app.currExp > app.initExpNumb
                
                % Disable the start button so that no more 'inital cond'
                % experiments can be run (even though they wont, but it
                % will prevent message from appearing).
                app.StartInitButton.Enable = false;
                app.InitLamp.Color = [0.65 0.65 0.65]; % Reset lmp color back to grey
                
                % If auto start is checked, then start the optimisation
                % code. If not then shut down HPLC.
                if app.AutostartCheckBox.Value == 1
                    app.StartOptButtonPushed()
                else
                   error = app.shutdownHPLC();
                   if error == true
                       % FUNCTION HERE THAT SENDS EMAIL TO NOTIFY SHUTDOWN MAY
                       % NOT HAVE OCCURED.
                       return
                   end
                end
                
            % If not shut down HPLC    
            else
               error = app.shutdownHPLC();
               if error == true
                   % FUNCTION HERE THAT SENDS EMAIL TO NOTIFY SHUTDOWN MAY
                   % NOT HAVE OCCURED.
                   return
               end
            end
            
        end

        % Value changed function: HPLCListBox
        function HPLCListBoxValueChanged(app, event)
            app.updateGraph()
        end

        % Value changed function: WavelengthSpinner
        function WavelengthSpinnerValueChanged(app, event)
            app.updateGraph()
            
        end

        % Button pushed function: LoadVarsButton
        function LoadVarsButtonPushed(app, event)
            [filename, currPath] = uigetfile('*.mat','Select a file to load', app.ResultsDir);
            
            % Creates the directory of where the new folder save will be
            app.folderSave = string(currPath(1:end-1));
            app.fileNameSave = filename;
            
            if filename == 0
                app.updateTextBox(false, "No file was selected.")
            else
                try
                    app.updateTextBox(true, "Loading data ...")

                    %Load the data file based on the name inputed into the text
                    %box. This is read as a structure. Convert the structure to a
                    %cell and then access the first element of this cell to 'open'
                    %it properly. (May be a better way to do this).
                    vars = load([currPath filename]);
                    vars = struct2cell(vars);
                    vars = vars{1,1};
                    
                    % Based on the contents of the file, set all the variables
                    
                    % Variables table
                    app.variables = vars{1,2};
                    
                    % Then overwrite the 'variables.mat' file with the updated
                    % conditions
                    vars2save = vars{1,2};
                    save("Variables.mat", "vars2save")
                    
                    % The actual condtions based on the LHS
                    app.initCondtions = vars{2,2};
                    % The results of each inital conditon
                    app.initResults = vars{3,2};

                    % Set the prevous graph and peaks data recorded
                    app.graphPeaksData = vars{6,2};
                    % Load the algorithm output
                    app.algOutput = vars{7,2};

                    % Load the settings - handles if settings have not been
                    % set before
                    app.loadSettings(vars);
                   
                    % If the first element of graph peak data is empty (is its a
                    % new save that has not had any experiments run yet), set the
                    % current experiment to 1. Else set it to the lenght of the
                    % list + 1 so that it is ready to run the next experiment.
                    if isempty(app.graphPeaksData{1,1})
                        app.currExp = 1;
                    else
                        app.currExp = size(app.graphPeaksData,1) + 1;
                    end
        
                    % Updates the graph list box
                    app.UpdateGraphList()
                    
                    % Set up the Var list box with all the variables
                    app.allVars = [app.variables.Name' app.respSelected];
                    app.VarListBox.Items = app.allVars;
                    app.SelectedvariablesListBox.Items = app.variables.Name;
                    app.SelectedresponcefunctionsListBox.Items = app.ObjectivefunctionsListBox.Items;
                    
                    % Then display the data in the text box
                    app.DisplayData()
                    
                    % Disable boxes so they can't be edited
%                     app.ObjectivefunctionsListBox.Enable = false;
%                     app.DatafilenameEditField.Enable = false;
%                     app.AlgorithmDropDown.Enable = false;
%                     app.HPLCDirectoryEditField.Enable = false;
%                     app.InitexpSpinner.Enable = false;
%                     app.EqulibrationtimeSpinner.Enable = false;
%                     app.StartInitButton.Enable = true;
%                     app.SamplePositionEditField.Enable = false;
                    app.ReprocessdataButton.Enable = true;
                    app.InitLamp.Color = [1 0 0]; %Turn inital condtions lamp to red (not running)
                    
                    % If the inital number of experiments run has been reached
                    % (meaning it is time to start optimising!)
                    if app.currExp > app.initExpNumb
                        % Disable the start button so that no more 'inital cond'
                        % experiments can be run (even though they wont, but it
                        % will prevent message from appearing).
                        app.StartInitButton.Enable = false;
                        app.StartOptButton.Enable = true;
                        app.InitLamp.Color = [0.65 0.65 0.65]; % Reset lmp color back to grey
                        app.OptLamp.Color = [1 0 0];
                    end
                    
                    app.ApplyVarsButton.Enable = false;
                    app.LoadVarsButton.Enable = false;
                    
                    % Create/load log file
                    app.logTrack = log4m.getLogger(app.folderSave + "\" + app.logFileName);
                    
                    app.logTrack.debug('LoadVarsButtonPushed', 'File successfuly loaded.')
                catch
                    app.resetUI();
                    app.updateTextBox(false, "File failed to load.")
                end
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetUI(); % Resets the UI back to the default
        end

        % Button pushed function: ConfigvarButton
        function ConfigvarButtonPushed(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = ConfigVars(app);
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            app.updateRespBox();
        end

        % Button pushed function: PauseInitButton
        function PauseInitButtonPushed(app, event)
            app.paused = true;
            app.updateTextBox(true, newline + "Waiting for current HPLC to finish and then will stop" + newline)
        end

        % Value changed function: ToggledebugCheckBox
        function ToggledebugCheckBoxValueChanged(app, event)
            app.updateGraph();
        end

        % Value changed function: Switchgraphstate
        function SwitchgraphstateValueChanged(app, event)
            app.updateGraph();
        end

        % Value changed function: VarListBox
        function VarListBoxValueChanged(app, event)
            app.updateGraph();     
        end

        % Button pushed function: StartOptButton
        function StartOptButtonPushed(app, event)

            % Start the HPLC. If an error occurs terminate the function.
            %%PFIZER EDIT HERE
            %Establish connection with HPLC, switch it on etc. Just a
            %general check to make sure the instrument is good to go!
            error = app.startHPLC();
            if error
                return
            end
            %%

            % Disiable buttons and configure settings
            app.ResetButton.Enable = false;
            app.StartOptButton.Enable = false;
            app.PauseOptButton.Enable = true;
            app.OptLamp.Color = [0 1 0]; % Change color to green (indicates the experiment has started)
            app.paused = false; % If set to true then this will stop.
            numbExp = app.OptexpSpinner.Value; % The number of experiments set by the user
            currExpOpt = 1; % The current experiment in this opt run
            
            % Update user that the opt has started.
            app.updateTextBox(true, newline + "***OPTIMISATION STARTED***" + newline + newline, true)
            
            while and(currExpOpt <= numbExp, app.paused == false)
                
                % Update value if it is changed (may be lowered if less are
                % needed to be run)
                numbExp = app.OptexpSpinner.Value;
                
                % Check if there are experiments that have not yet been run
                % but have been added by the optimisation algorithms.
                if app.currExp <= size(app.initCondtions,1)
                    app.updateTextBox(true, newline + "Experiment " + string(app.currExp) + " has already been defined. " + ...
                        "Bypassing opt algorithm unitll all defined experiments have been run." + newline, true)
                else
                
                    % Returns the variables that are being changed (the ones
                    % being optimised), the results of the objective functions
                    % (only returns an objective function if the value of the
                    % function is being varied), as well as the upper and lower
                    % bounds of each continous variable used. This data is fed
                    % into the optimisation algorithm.
                    try
                        [usedVariables, usedResults, LB, UB, ~, disData] = app.checkVariables();
                    catch
                        app.updateTextBox(true, newline + "Error processing variables, stopping optimisation." + newline)
                        app.logTrack.error('StartOptButtonPushed', 'Error processing variables for optimisation.')
                        return
                    end
                    
                    % Given this data and the responce function, feed this into
                    % the opt algorithm that has been choosen, which retruns
                    % the results.
                    
                    try
                        % Find the index of the algorithm selected so that it can
                        % point to its function handle
                        
                        ind = find(contains(app.algorithms{1,:}, app.algSelected));
                        funcHandle = app.algorithms{2,:}{ind};
                        optHandle = app.algorithms{3,:}{ind};
                        
                        % Runs the code that sets the options for the optimisation
                        % algorithn selected. (These options can be changed in
                        % their respective code files)
                        options = feval(optHandle);
                        
                        % Run the opt algorithm given the optimisation function has
                        % the following structure: func(optimiseFunc, Xvars, Yvars,
                        % LB, UB, options). Stores all the output into a cell
                        % array.
                        

                        X = feval(funcHandle, usedVariables, usedResults, LB, UB, options, disData);
                        
                        % If more than one experiment is returned, add them
                        % all to the list
                        if size(X,1) > 1
                            app.updateTextBox(true, newline + string(size(X,1)) + " new experiments have been reccomended. " + ...
                                "Adding each of these experiments to the list, which will run in the following iterations." + newline, true)
                        end
                        
                        % If discrete variables are being used, update the
                        % LB and UB back to the orginal
                        if ~isempty(disData)
                            % Now swap any discrete variables back to their actual
                            % values for when the actual experiment is run,
                            % in each experiment.
                            for exp = 1:size(X,1)
                                actualLBUBInd = find(app.variables.DiscreteOrContinuous == 'D' & app.variables.Optimise == 1 );
                                actualLBUB = app.variables.Data(actualLBUBInd);
                                for disVar = 1:size(disData,2)
                                    lenOfDis = disData(2,disVar);
                                    currVars = 1:lenOfDis;
                                    LBUB = cell2mat(actualLBUB(disVar));
                                    for swap = 1:lenOfDis
                                        if (X(exp,disData(1,disVar)) == currVars(swap))
                                            X(exp,disData(1,disVar)) = LBUB(swap);
                                        end
                                    end
                                end
                            end
                        end
                        
                    catch % If variables failed to process.
                        app.updateTextBox(true, newline + "Algorithm failed to evaluate next set of condtions, stopping optimisation." + newline)
                        app.logTrack.error('StartOptButtonPushed', 'Algorithm failed to evaluate next set of condtions, stopping optimisation.')
                        return
                    end
                    
                    % Based on X, add this data to the inital experiments
                    % table.
                    currVar = 1;
                    curExp = app.currExp;
                    
                    % Loops through each experiment (if there is more than
                    % 1)
                    for exp = 1:size(X,1)
                        % Loops through each variable]
                        for var = 1:length(app.variables.Optimise)
                            % If the vairable is being optimised add it to the
                            % row and column in init condtions
                            if app.variables.Optimise(var) == 1
                                app.initCondtions{curExp, var} = X(exp,currVar);
                                currVar = currVar + 1; % Add one to the current variable
                            else
                                % If the variable is continous
                                if app.variables.DiscreteOrContinuous(var) == "C"
                                    % Add it as normal as it is a number already
                                    app.initCondtions{curExp, var} = app.variables.Data{var};
                                % if the variable is discrete    
                                elseif app.variables.DiscreteOrContinuous(var) == "D"
                                    % Convert it to a number first and then add it.
                                    app.initCondtions{curExp, var} = str2double(app.variables.Data{var});
                                end
                            end
                        end
                        curExp = curExp + 1; % Add one to the current experiments
                        currVar = 1;
                    end
                end
        
                % Notfify the user the experiment has started
                c = clock;
                time = string(c(4)) + ":" + string(c(5));
                app.updateTextBox(true, "Experiment " + string(app.currExp) + " started at " + time + newline)
                
                app.updateTextBox(true, "Condtions: " + newline)
                app.writeCurrExperiment(app.currExp)
                
                app.updateTextBox(true, "Waiting for method to finish..." + newline + newline)
                
                % Attempt to run the condtion that has been set.
                try
                    % Run  condtion that has been set depending on the
                    % current experiment
                    %%PFIZER EDIT HERE
                    %This calls the function(s) to write the method to the
                    %instrument. If it fails it will reach the "timeout"
                    %and the app will return an error
                    timeout = RunCond(app.initCondtions, app.currExp, app.equalTime, app.errorTime);
                    %%
                    if timeout == true
                        app.updateTextBox(true, newline + "Experiment took longer than " + string(app.errorTime) + " minutes to finish so aborted." + newline)
                        app.logTrack.error('StartOptButtonPushed', 'Experiment took too long to run (' + string(app.errorTime) + ' minutes)')
                        return
                    end
                    
                    % Notify the user the time the method finished at.
                    c = clock;
                    time = string(c(4)) + ":" + string(c(5));
                    app.updateTextBox(true, "Method finished at " + time + newline, true)
                
                catch % If this fails notfiy the user and terminate the function
                    
                    app.updateTextBox(true, newline + "Failed to run the optimisation experiment. Ending the optimistion." + newline)
                    app.logTrack.error('StartOptButtonPushed', 'RunCondions failed to run an experiment.')
                    return
                    
                end
                
                % Get the file name from the excel file. If this fails an 
                % error is displayed and the function is terminated.

                %%PFIZER EDIT HERE
                %This obtains the file location for the HPLC data (both the
                %chromatogram data and the peaks data). May not be neeed
                %with the API now.
                [error, extension] = app.getfilenameHPLC();
                if error == true
                    return
                end
                %%

                % Attempt to read the HPLC file
                try
                    app.updateTextBox(true, "Reading method file ..." + newline + newline, true)
                    
                    % Read and process the data file
                    % Imports the data using importAgilent function, created by ... and
                    % tidies the data, as well as importing it into a table

                    %%PFIZER EDIT HERE
                    %This extracts all the chromatogram data including: the
                    %time intensity data for each channel set up on the
                    %method and the DAD detector data if set up for the
                    %190-400 nm spectra
                    data = importAgilent2("file", app.hplcDirectory + "\" + extension, 'verbose', 'off');
                    %%

                    if app.VWDmonitorButton.Value == 1
                        data = data(app.VWDMonitorNumber.Value + 1,:);
                        wavelength = data.channel_units;
                    else
                        data = data(1,:);
                        wavelength = data.channel; % Wavelengths
                    end
                    
                    time = data.time; % Time data
                    intensity = data.intensity;

                    % Save the data in the graphs and Peaks cell which then
                    % later gets saved to the current file
                    app.graphPeaksData{app.currExp,1} = extension;
                    app.graphPeaksData{app.currExp,2} = time;
                    app.graphPeaksData{app.currExp,3} = wavelength;
                    app.graphPeaksData{app.currExp,4} = intensity; % Unmodified intensity
                    app.graphPeaksData{app.currExp,6} = intensity; % Unmodified intensity
                    
                    % Normalise the intensity at each wavelength depending
                    % on the maximum and minimum values of all the
                    % chromatograms (into the 4th column of graphPeaksData)
                    % and pick the peaks
                    normiliseAllIntensities(app);   
                    
                catch % If this fails notfiy the user and terminate the function
                    
                    app.updateTextBox(true, newline + "Failed to read HPLC file" + newline)
                    app.logTrack.error('StartOptButtonPushed', 'Failed to read HPLC file.')
                    return
                end
                
                
                % Attempt to calculate the responce variables from the
                % results.
                try
                    app.updateTextBox(true, "Calculating responce variables ..." + newline + newline, true)
                    app.CalcResponces(); 
                    app.updateTextBox(true, "Responce vars generated. Experiment " + string(app.currExp) + " finished. Results:" + newline, true)
                    
                catch % If this fails notfiy the user and terminate the function
                    
                    app.updateTextBox(true, newline + "Failed to calculate responce variables." + newline)
                    app.logTrack.error('StartOptButtonPushed', 'Failed to calculate responce variables.')
                    return
                end
                
                % Write the updated condtions with the responces
                app.writeCurrExperiment(app.currExp, true)
                
                % Update the graph box with the new data
                app.UpdateGraphList();
                
                % Save the data to the file before running the next 
                app.saveVarFile();
                
                % Increment the current experiment number
                app.currExp = app.currExp + 1;
                currExpOpt = currExpOpt + 1;
                
            end
            
            app.updateTextBox(true, newline + "***OPTIMISATION FINISHED***" + newline + newline)
            
            error = app.shutdownHPLC();
            if error == true
               % FUNCTION HERE THAT SENDS EMAIL TO NOTIFY SHUTDOWN MAY
               % NOT HAVE OCCURED.
               return
            end
            
            app.ResetButton.Enable = true;
            app.StartOptButton.Enable = true;
            app.PauseOptButton.Enable = false;
            app.OptLamp.Color = [1 0 0]; % Change color to green (indicates the experiment has started)
        end

        % Button pushed function: PauseOptButton
        function PauseOptButtonPushed(app, event)
            app.paused = true;
            app.updateTextBox(true, newline + "Waiting for current run to finish before stopping" + newline + newline)
        end

        % Button pushed function: ReprocessdataButton
        function ReprocessdataButtonPushed(app, event)
            % Code for the pop up box to warn the user that it will change
            % the responce function values. If no is selected nothing
            % happens. Look below for yes.
            selection = uiconfirm(app.HPLC, "Are you sure you want to " + ...
                "reprocess? This will change the values of the responce " + ...
                "functions.", "Reprocess", "Icon","warning","Options", ...
                ["Yes", "No"], "DefaultOption", 2, "CancelOption",2);
            
            % If yes is selected
            if selection == "Yes"
                try
                    app.ResetButton.Enable = false;
                    app.ReprocessdataButton.Enable = false;
                    
                    app.saveSettings();
                    app.normiliseAllIntensities();
                    app.CalcResponces();
    
                    % Saves the new data to the .mat file
                    app.saveVarFile();
                    
                    % Updates the graph so the user can see if the data has
                    % changed
                    app.updateGraph();
                    
                    app.logTrack.debug('ReprocessButtonPushed', 'Variables sucessfully reprocessed')
                    
                    % Reset the data display
                    app.DisplayData(true);
                    
                    % Enable buttons again
                    app.ResetButton.Enable = true;
                    app.ReprocessdataButton.Enable = true;
                
                catch
                    app.logTrack.error('ReprocessButtonPushed', 'Failed to reprocess variables')
                    app.updateTextBox(true, "Failed to reprocess variables", true)
                    
                end
            end


        end

        % Value changed function: PlotparetodataCheckBox
        function PlotparetodataCheckBoxValueChanged(app, event)
            app.updateGraph();
        end

        % Button pushed function: HPLCDirectoryButton
        function HPLCDirectoryButtonPushed(app, event)
            app.HPLCDirectoryEditField.Value = uigetdir();
        end

        % Value changed function: ThreeDSet
        function ThreeDSetValueChanged(app, event)
            app.updateGraph();   
        end

        % Value changed function: PlotBestIntensities
        function PlotBestIntensitiesValueChanged(app, event)
            app.updateGraph();
            
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: ReprocessfilesButton
        function ReprocessfilesButtonPushed(app, event)
%              [filename, currPath] = uigetfile('*.mat','Select a file to load', app.ResultsDir);
%             
%             % Creates the directory of where the new folder save will be
%             app.folderSave = string(currPath(1:end-1));
%             app.fileNameSave = filename;
%             
%             if filename == 0
%                 app.updateTextBox(false, "No file was selected.")
%             else
%                 try
%                     %Load the data file based on the name inputed into the text
%                     %box. This is read as a structure. Convert the structure to a
%                     %cell and then access the first element of this cell to 'open'
%                     %it properly. (May be a better way to do this).
%                     vars = load([currPath filename]);
%                     vars = struct2cell(vars);
%                     vars = vars{1,1};
%                     
%                     % Based on the contents of the file, set all the variables
%                     
%                     % Variables table
%                     app.variables = vars{1,2};
%                     
%                     % Then overwrite the 'variables.mat' file with the updated
%                     % conditions
%                     vars2save = vars{1,2};
%                     save("Variables.mat", "vars2save")
%                     
%                     % The actual condtions based on the LHS
%                     app.initCondtions = vars{2,2};
%                     % The results of each inital conditon
%                     app.initResults = vars{3,2};
%                     % The responce functions selected
%                     app.respSelected = cellfun(@string, app.initResults.Properties.VariableNames);
%                     % The algorithm that has been selected
%                     app.algSelected = vars{4,2};
%                     % Sets what the inital number of experiments was
%                     app.initExpNumb = vars{5,2};
%                     % Set the spinner to this value
%                     app.InitexpSpinner.Value = vars{5,2};
%                     % Set the prevous graph and peaks data recorded
%                     app.graphPeaksData = vars{6,2};
%                     % Load the algorithm output
%                     app.algOutput = vars{7,2};
%                    
%                     % Sets the equlabration time
%                     app.equalTime = app.EqulibrationtimeSpinner.Value;
%                     % Sets the HPLC directory (where the file is read from)
%                     app.hplcDirectory = app.HPLCDirectoryEditField.Value;
%                     % Sets the sample pos vial
%                     app.samplePos = app.SamplePositionEditField.Value;
%         
%                     % If the first element of graph peak data is empty (is its a
%                     % new save that has not had any experiments run yet), set the
%                     % current experiment to 1. Else set it to the lenght of the
%                     % list + 1 so that it is ready to run the next experiment.
%                     if isempty(app.graphPeaksData{1,1})
%                         app.currExp = 1;
%                     else
%                         app.currExp = size(app.graphPeaksData,1) + 1;
%                     end
%         
%                     % Updates the graph list box
%                     app.UpdateGraphList()
%                     
%                     % Set up the Var list box with all the variables
%                     app.allVars = [app.variables.Name' app.respSelected];
%                     app.VarListBox.Items = app.allVars;
%                     
%                     % Then display the data in the text box
%                     app.DisplayData()
%                     
%                     % Disable boxes so they can't be edited
%                     app.ObjectivefunctionsListBox.Enable = false;
%                     app.DatafilenameEditField.Enable = false;
%                     app.AlgorithmDropDown.Enable = false;
%                     app.HPLCDirectoryEditField.Enable = false;
%                     app.InitexpSpinner.Enable = false;
%                     app.EqulibrationtimeSpinner.Enable = false;
%                     app.StartInitButton.Enable = true;
%                     app.SamplePositionEditField.Enable = false;
%                     app.ReprocessdataButton.Enable = true;
%                     app.InitLamp.Color = [1 0 0]; %Turn inital condtions lamp to red (not running)
%                     
%                     % If the inital number of experiments run has been reached
%                     % (meaning it is time to start optimising!)
%                     if app.currExp > app.initExpNumb
%                         % Disable the start button so that no more 'inital cond'
%                         % experiments can be run (even though they wont, but it
%                         % will prevent message from appearing).
%                         app.StartInitButton.Enable = false;
%                         app.StartOptButton.Enable = true;
%                         app.InitLamp.Color = [0.65 0.65 0.65]; % Reset lmp color back to grey
%                         app.OptLamp.Color = [1 0 0];
%                     end
%                     
%                     app.ApplyVarsButton.Enable = false;
%                     app.LoadVarsButton.Enable = false;
%                     
%                     % Create/load log file
%                     app.logTrack = log4m.getLogger(app.folderSave + "\" + app.logFileName);
%                     
%                     app.logTrack.debug('LoadVarsButtonPushed', 'File successfuly loaded.')
%                 catch
%                     app.resetUI();
%                     app.updateTextBox(false, "File failed to load.")
%                 end
        end

        % Button pushed function: FitmodelandplotButton
        function FitmodelandplotButtonPushed(app, event)

            testFuncSelected = app.TestfunctionsDropDown.Value;
            % If no test function is selected
            if testFuncSelected == "None"
                
                % Gathers the variables that have been selected to be
                % fitted to a model e.g. gradient time, converts them to a
                % string array and stores the number of them.
                variablesSelected = app.SelectedvariablesListBox.Value;
                variablesSelected =  cellfun(@string, variablesSelected);
                numbVariablesSelected = length(variablesSelected);
                
                % Gathers the experiements selected to fit the model too.
                % Converts them to a string array.
                experimentsSelected = app.ExperimentstofittomodelListBox.Value;
                experimentsSelected =  cellfun(@string, experimentsSelected);
                
                % Stores which responce variable has been selected (only
                % one currently), the model to be fitted to the selected
                % data and the algorithm that will be used to optimise the
                % model.
                responcesSelected = app.SelectedresponcefunctionsListBox.Value;
                modelSelected = app.ModeltofitDropDown.Value;
                algorithmSelected = app.OptalgorithmDropDown.Value;
    
                % Using the names of all the different variables and
                % experiements, find their index.
                allVariables = cellfun(@string, app.initCondtions.Properties.VariableNames);
                indcol = find(contains(allVariables, variablesSelected));
                indrow = find(contains(app.chromatogramLable, experimentsSelected));
        
                % Use this index to extract the results and conditions from
                % the actual data recorded.
                conditonsSelected = app.initCondtions{indrow, indcol};
                resultSelected = app.initResults{indrow, responcesSelected};
                resultSelected = cell2mat(resultSelected);
    
                % If no "custom" model is entered in the custom box, then
                % use the model selected. Otherwise use the model
                % specified.
                if isempty(app.customEditField.Value)
                    
                    % If the linear interpolation model has been selected
                    % just set it as "interp", otherwise set fit the model
                    % to the data
                    if app.ModeltofitDropDown.Value == "interp"
                        modelFitted = "interp";
                    else
                        modelFitted = fitlm(conditonsSelected, resultSelected, modelSelected);
                    end
                else
                    modelFitted = fitlm(conditonsSelected, resultSelected, app.customEditField.Value);
                end
    
                % Save these variables to a app structure to be accessed
                % later.
                app.simulateCondtions.conditonsSelected = conditonsSelected;
                app.simulateCondtions.resultSelected = resultSelected;
                app.simulateCondtions.modelFitted = modelFitted;
                app.simulateCondtions.variablesSelected = variablesSelected;
                app.simulateCondtions.algorithmSelected = algorithmSelected;
    
                % Extract the lower and upper bounds of the variables
                % selected (continous only at the moment).
                indVars = find(contains(app.variables.Name,app.simulateCondtions.variablesSelected));
                data = app.variables.Data(indVars)';
                LB = cellfun(@min, data);
                UB = cellfun(@max, data);
                
                % Store these varibles
                app.simulateCondtions.LB = LB;
                app.simulateCondtions.UB = UB;

                if modelFitted == "interp"
                    % Only works in 3 dimensions
                    dx = LB(1):0.1:UB(1);
                    dy = LB(2):0.1:UB(2);
                    dz = LB(3):0.1:UB(3);
                    
                    [xx,yy,zz] = meshgrid(dx,dy,dz);
                    
                    xq = [xx(:) yy(:) zz(:)];
                    vq = griddatan(conditonsSelected,resultSelected,xq,'linear');
                    vq = reshape(vq,size(xx));

                    scatter3(app.liveplot,conditonsSelected(:,1),conditonsSelected(:,2),conditonsSelected(:,3),20,resultSelected, "filled")
                    hold(app.liveplot, "on")
                    slice(app.liveplot,xx,yy,zz,vq,[2,5,9],30,5)
                    hold(app.liveplot, "off")
                    shading(app.liveplot,"flat")

                    app.simulateCondtions.vq = vq;
                    app.simulateCondtions.xx = xx;
                    app.simulateCondtions.yy = yy;
                    app.simulateCondtions.zz = zz;

                    z = vq;
    
                % Plot the graphs. Only works for up to three responces 
                % selected
                elseif numbVariablesSelected == 1
                    % Plot the selected data points
                    scatter(app.liveplot, conditonsSelected, resultSelected, "k", "filled")
                
                    % Depending on model selected, fit data points to this
                    % model and predict results between upper and lower
                    % bounds stated.
                    x1Pred = linspace(LB(1),UB(1),100)';
                    z = predict(modelFitted, x1Pred);

                    % Plot the predicted data points onto the same graph
                    % and label axis.
                    hold(app.liveplot, "on")
                    plot(app.liveplot, x1Pred, z);
                    hold(app.liveplot, "off")
                    xlabel(app.liveplot, variablesSelected)
                    ylabel(app.liveplot, responcesSelected)

                    % Min value calculated for the function
                    [app.simulateCondtions.minPredValue, I] = min(z, [], 'all');
    
                elseif numbVariablesSelected == 2
                    % Plot the selected data points
                    scatter3(app.liveplot, conditonsSelected(:,1), conditonsSelected(:,2),resultSelected, "k", "filled")
                    
                    % Depending on model selected, fit data points to this
                    % model and predict results between upper and lower
                    % bounds stated.
                    x1Pred = linspace(LB(1),UB(1),100)'; 
                    x2Pred = linspace(LB(2),UB(2),100)';
                    [x1,x2] = meshgrid(x1Pred, x2Pred); 
                    z = reshape(predict(modelFitted, [x1(:),x2(:)]),size(x1));
    
                    % Plot the surface generated and the axis labels
                    hold(app.liveplot, "on")
                    surf(app.liveplot,x1Pred,x2Pred,z,'FaceAlpha',0.5,'edgecolor','none')
                    hold(app.liveplot, "off")
                    xlabel(app.liveplot, variablesSelected(1))
                    ylabel(app.liveplot, variablesSelected(2))
                    zlabel(app.liveplot, responcesSelected)

                    % Min value calculated for the function
                    [app.simulateCondtions.minPredValue, I] = min(z, [], 'all');

                elseif numbVariablesSelected == 3
                    % ADD RESPONCE SURFACE HERE?
                    scatter3(app.liveplot, conditonsSelected(:,1), conditonsSelected(:,2),conditonsSelected(:,3),36, resultSelected, "filled")
                    
                    % Calculate predicted model to plot
                    x1Pred = linspace(LB(1),UB(1),100)'; 
                    x2Pred = linspace(LB(2),UB(2),100)';
                    x3Pred = linspace(LB(3),UB(3),100)';
                    [x1,x2,x3] = meshgrid(x1Pred, x2Pred, x3Pred); 
                    z = reshape(predict(modelFitted, [x1(:),x2(:), x3(:)]),size(x1));
                    
                    % Set slider limits and default values
                    app.xSlider.Limits = [LB(1) UB(1)];
                    app.ySlider.Limits = [LB(2) UB(2)];
                    app.zSlider.Limits = [LB(3) UB(3)];
                    app.xSlider.Value = LB(1);
                    app.ySlider.Value = LB(2);
                    app.zSlider.Value = LB(3);
                    
                    % Define slices based on LB and UB to give cube effect
                    xslice = [LB(1) UB(1)];                               % define the cross sections to view
                    yslice = [LB(2), UB(2)];
                    zslice = [LB(3) UB(3)];

                    % Plot the slices and flat shading to remove grid
                    % effect
                    hold(app.liveplot, "on")
                    slice(app.liveplot,x1, x2, x3, z, xslice, yslice, zslice)    % display the slices
                    shading(app.liveplot, "flat")
                    hold(app.liveplot, "off")
                    
                    % Labels and hot colour map
                    xlabel(app.liveplot, variablesSelected(1))
                    ylabel(app.liveplot, variablesSelected(2))
                    zlabel(app.liveplot, variablesSelected(3))
                    colormap(app.liveplot, "hot")
                    cb = colorbar(app.liveplot, "eastoutside");
                    title(cb, responcesSelected)
                    
                    % Store for when the slice limits are changed
                    app.simulateCondtions.x1 = x1;
                    app.simulateCondtions.x2 = x2;
                    app.simulateCondtions.x3 = x3;
                    app.simulateCondtions.z = z;
                end
            
                % Save text from model output to be displayed
                app.txtForSimulation = evalc('modelFitted');
                
                % Min value calculated for the function
                [app.simulateCondtions.minPredValue, I] = min(z, [], 'all');

                app.txtForSimulation = [app.txtForSimulation "Min value is: " num2str(app.simulateCondtions.minPredValue)];

                app.TextArea.Value = app.txtForSimulation;
            
            % If a test function is selected
            else
                app.txtForSimulation = "";
                app.TextArea.Value = app.txtForSimulation;

                indFuncHand = find(contains(app.testFunctions{1,:}, testFuncSelected));
                functionHandle = app.testFunctions{2,:}{indFuncHand};

                [conditonsFunc{1:5}] = feval(functionHandle);
                app.simulateCondtions.dim = conditonsFunc{1,1};
                app.simulateCondtions.LB = conditonsFunc{1,2};
                app.simulateCondtions.UB = conditonsFunc{1,3};
                app.simulateCondtions.posMin = conditonsFunc{1,4};
                app.simulateCondtions.calcMin = conditonsFunc{1,5};
                app.simulateCondtions.algorithmSelected = app.OptalgorithmDropDown.Value;

                % Name   : Rody P.S. Oldenhuis
                % E-mail : oldenhuis@gmail.com
                % Licence: 2-clause BSD (See Licence.txt)
                
                % If you find this work useful, please consider a donation:
                % https://www.paypal.me/RodyO/3.5
                
                % If you want to cite this work in an academic paper, please use
                % the following template:
                %
                % Rody Oldenhuis, orcid.org/0000-0002-3162-3660. "testfunctions" <version>,
                % <date you last used it>. MATLAB Test functions for global optimization
                % algorithms. https://nl.mathworks.com/matlabcentral/fileexchange/23147-testfunctions

                x1 = linspace(app.simulateCondtions.LB(1), app.simulateCondtions.UB(1), 150);
                x2 = linspace(app.simulateCondtions.LB(2), app.simulateCondtions.UB(2), 150);
                x3 = zeros(length(x1), length(x2));
        
                % simply loop through the function (most functions expect
                % [N x 2] vectors as input, so meshgrid does not work)
                for ii = 1:length(x1)
                    for jj = 1:length(x2)
                        x3(ii, jj) = functionHandle([x1(ii), x2(jj)]);
                    end
                end
        
                % build simple grid for axes
                [x1, x2] = meshgrid(x1, x2);
        
                surf(app.liveplot,x1', x2', x3, 'Linestyle', 'none','edgecolor','none')
                
                hold(app.liveplot, "on")
                % draw minima
                if ~isnan(app.simulateCondtions.posMin)
                    plot3(app.liveplot, app.simulateCondtions.posMin(:,1), app.simulateCondtions.posMin(:,2), repmat(app.simulateCondtions.calcMin, size(app.simulateCondtions.posMin,1), 1), ...
                        'y*', 'MarkerSize', 20);
                end
        
                % tiles, labels, legend
                xlabel(app.liveplot,'x_1'), ylabel(app.liveplot,'x_2'), zlabel(app.liveplot,'F(x_1, x_2)')
                if (size(app.simulateCondtions.posMin,1) > 1)
                    legend(app.liveplot,[func2str(functionHandle), '-function'], 'Global Minima')
                else
                    legend(app.liveplot,[func2str(functionHandle), '-function'], 'Global Minimum')
                end

                hold(app.liveplot, "off")

                view(app.liveplot,-40, 30)
            end

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            % Pre sets the simulation reults variable and the file name
            % based on the time
            simulationResults = cell(1,1);
            time = datestr(clock,'YYYY-mm-dd HH-MM-SS');
            filename = "Simulations/" + time + ".mat";

            % Loop through depending on the number set in 'repeat
            % simulation'
            for simulationNumb = 1:app.RepeatsimulationEditField.Value
                
                % Extract the selected algorithm and obtain its function
                % handle as well as its options function handle
                ind = find(contains(app.algorithms{1,:}, app.simulateCondtions.algorithmSelected));
                funcHandleOpt = app.algorithms{2,:}{ind};
                optHandle = app.algorithms{3,:}{ind};
                options = feval(optHandle);
                disData = []; % Not yet used but required for calling the opt function later
                
                % Extracts the value of the test function selected. If it
                % is 'None', then it uses the data selected in the lists
                % above, otherwise the selected points are overrided and
                % the selected test function is loaded instead.
                testFuncSelected = app.TestfunctionsDropDown.Value;
                app.simulateCondtions.testFuncSelected = testFuncSelected;
                if testFuncSelected == "None"           
                    
                    % Obtains the names of the variables selected, finds
                    % their index and then passes this through a function
                    % that sets the varables as discrete or continous
                    names = app.simulateCondtions.variablesSelected;
                    indVars = find(contains(app.variables.Name,app.simulateCondtions.variablesSelected));
                    dorc = app.variables.DiscreteOrContinuous(indVars)';
    
                else
                    % Obtains the test function handle and sets all the
                    % varables as continous
                    indFuncHand = find(contains(app.testFunctions{1,:}, testFuncSelected));
                    funcHandleTestFunc = app.testFunctions{2,:}{indFuncHand};
                    dorc = repelem("C", length(app.simulateCondtions.LB));
                    
                end
    
                app.txtForSimulation = [app.txtForSimulation  newline "Starting Optimisation!"];
                app.TextArea.Value = app.txtForSimulation;
    
                % Sets the upper and lower bounds based on whats been
                % defined.
                LB = app.simulateCondtions.LB ;
                UB = app.simulateCondtions.UB ;
                
                % Builds the inital condtions based on the a Latin hypercube
                % sampling desgin and changes the upper and lower bounds based
                % on the values set
                tempData = zeros(app.InitalLHSexperimentsEditField.Value, length(LB));
                LHSSpace = lhsdesign(app.InitalLHSexperimentsEditField.Value, length(LB));  % Latin hypercube design
                counter = 1;
                for k = 1:length(LB)
                    
                    % If the value is being optimised and is continous var
                    if dorc(k) == "C"
                        % Set value based on the upper and lower bounf
                        tempData(:,k) = round(LHSSpace(:,counter).*(UB(k)-LB(k))+LB(k),2);        % adjustment of bounds
                        counter = counter + 1;
    
                    % If the value is discrete and is being optimise
                    % COMMENT
                    elseif dorc(k) == "D"
                        index = round(LHSSpace(:,counter)*(length([LB(k) UB(k)])-1),0)+1;
                        extendedData = [LB(k) UB(k)];
                        tempData(:,k) = extendedData(:,index)';
                        counter = counter + 1;
                    end
                end
                
                % Saves the inital condtions based on the LHS
                app.simulateCondtions.initalCondtions = tempData;
                
                % Obtains the results from the LHS
                if testFuncSelected == "None"
                    if app.simulateCondtions.modelFitted == "interp"
                        app.simulateCondtions.initalCondtions
                    else
                        app.simulateCondtions.initalResults = predict(app.simulateCondtions.modelFitted, app.simulateCondtions.initalCondtions);
                    end
                else
                    % simply loop through the function (most functions expect
                    % [N x 2] vectors as input, so meshgrid does not work)
                    results = [];
                    for ii = 1:size(tempData,1)
                        results(ii) = funcHandleTestFunc([tempData(ii,1) tempData(ii,2)]);
                    end
                    
                    app.simulateCondtions.initalResults = results';
                end
    
    
                app.simulateCondtions.allCondtions = tempData;
                app.simulateCondtions.allResults = app.simulateCondtions.initalResults;
    
                app.simulateCondtions.minResponceReturn = [];
                for currItem = 1:size(app.simulateCondtions.initalResults,1)
                    app.simulateCondtions.minResponceReturn(currItem) = min(app.simulateCondtions.initalResults(1:currItem,:),[],1);
                end
    
                
                scatter(app.UIAxes, 1:size(app.simulateCondtions.initalResults,1), app.simulateCondtions.minResponceReturn, "green", "filled");
    
                hold(app.liveplot, "on")
                if size(LHSSpace,2) == 1
                    scatter(app.liveplot,app.simulateCondtions.allCondtions(:,1),app.simulateCondtions.allResults, 36, "green", "filled")
                elseif size(LHSSpace,2) == 2
                    scatter3(app.liveplot,app.simulateCondtions.allCondtions(:,1),app.simulateCondtions.allCondtions(:,2),app.simulateCondtions.allResults, 36, "green", "filled")
                elseif size(LHSSpace,2) == 3
                    scatter3(app.liveplot,app.simulateCondtions.allCondtions(:,1), app.simulateCondtions.allCondtions(:,2), app.simulateCondtions.allCondtions(:,3), app.simulateCondtions.allResults,"filled")
                end
                hold(app.liveplot, "off")
    
    
                pause(0.25)
    
                iterations = 0;
                while iterations <= app.NumberofiterationsEditField.Value
                            
                    % Run the opt algorithm given the optimisation function has
                    % the following structure: func(optimiseFunc, Xvars, Yvars,
                    % LB, UB, options). Stores all the output into a cell
                    % array.
                    
                    X = feval(funcHandleOpt, app.simulateCondtions.allCondtions, app.simulateCondtions.allResults, LB, UB, options, disData);
                    app.simulateCondtions.allCondtions = [app.simulateCondtions.allCondtions;X(:,1:length(LB))];
    
                    iterations = iterations + size(X,1);

                    if testFuncSelected == "None"
                        result = predict(app.simulateCondtions.modelFitted, X(:,1:length(LB)));
                    else
                        % simply loop through the function (most functions expect
                        % [N x 2] vectors as input, so meshgrid does not work)
                        result = [];
                        for ii = 1:size(X,1)
                            result(ii) = funcHandleTestFunc([X(ii,1) X(ii,2)]);
                        end
                        result = result';
                    end
    
                    app.simulateCondtions.allResults = [app.simulateCondtions.allResults; result];
    
                    hold(app.liveplot, "on")
                    if length(LB) == 1
                        scatter(app.liveplot,X(:,1),result, 36, "red", "filled")
                    elseif length(LB) == 2
                        scatter3(app.liveplot,X(:,1),X(:,2),result, 36, "red", "filled")
                    elseif length(LB) == 3
                        for y = 1:size(X,1)
                            scatter3(app.liveplot,X(y,1),X(y,2),X(y,3), 36, result(y),"filled")
                        end
                    end
                    hold(app.liveplot, "off")
    
                    app.txtForSimulation = [app.txtForSimulation  newline num2str(iterations) " . " mat2str(X)  " ---> " mat2str(result)];
                    app.TextArea.Value = app.txtForSimulation;
                    pause(0.25)
                    
                    hold(app.UIAxes, "on")
                    for minResponces = 1:size(X,1)
                        app.simulateCondtions.minResponceReturn(end+1) = min(app.simulateCondtions.allResults(1:(size(app.simulateCondtions.allResults,1)-size(X,1)+minResponces)),[],1);
                        scatter(app.UIAxes, minResponces+size(app.simulateCondtions.allResults,1)-size(X,1), app.simulateCondtions.minResponceReturn(end), "red", "filled");
                    end
                    
                    hold(app.UIAxes, "off")
    
                end
    
                app.txtForSimulation = [app.txtForSimulation  newline "Finished!"];
                app.TextArea.Value = app.txtForSimulation;
    
    
                simulationResults(simulationNumb) = {app.simulateCondtions};
                
                save(filename, "simulationResults")
            end
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            changingValue = event.Value;
            xslice = [app.xSlider.Value app.simulateCondtions.UB(1)];                               % define the cross sections to view
            yslice = [app.ySlider.Value app.simulateCondtions.UB(2)];
            zslice = [changingValue app.simulateCondtions.UB(3)];
            
            slice(app.liveplot,app.simulateCondtions.x1, app.simulateCondtions.x2, app.simulateCondtions.x3, app.simulateCondtions.z, xslice, yslice, zslice)    % display the slices
            shading(app.liveplot, "flat")
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            changingValue = event.Value;
            xslice = [app.xSlider.Value app.simulateCondtions.UB(1)];                               % define the cross sections to view
            yslice = [changingValue app.simulateCondtions.UB(2)];
            zslice = [app.zSlider.Value app.simulateCondtions.UB(3)];

            slice(app.liveplot,app.simulateCondtions.x1, app.simulateCondtions.x2, app.simulateCondtions.x3, app.simulateCondtions.z, xslice, yslice, zslice)    % display the slices
            shading(app.liveplot, "flat")
            
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            changingValue = event.Value;
            xslice = [changingValue app.simulateCondtions.UB(1)];                               % define the cross sections to view
            yslice = [app.ySlider.Value app.simulateCondtions.UB(2)];
            zslice = [app.zSlider.Value app.simulateCondtions.UB(3)];

            slice(app.liveplot,app.simulateCondtions.x1, app.simulateCondtions.x2, app.simulateCondtions.x3, app.simulateCondtions.z, xslice, yslice, zslice)    % display the slices
            shading(app.liveplot, "flat")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HPLC = uifigure('Visible', 'off');
            app.HPLC.Name = 'HPLC Opt';
            app.TabGroup = uitabgroup(app.HPLC);
            app.DefineinputsTab = uitab(app.TabGroup);
            app.DefineinputsTab.Title = 'Define inputs';
            app.Step1Label = uilabel(app.DefineinputsTab);
            app.Step1Label.Text = 'Step 1: Define starting conditions';
            app.HPLCDirectoryEditField = uieditfield(app.DefineinputsTab, 'text');
            app.HPLCDirectoryEditField.Editable = 'off';
            app.HPLCDirectoryEditField.Value = 'C:\Users\Public\Documents\ChemStation\1\Data\cmtmdHPLC017Opt001';
            app.ApplyVarsButton = uibutton(app.DefineinputsTab, 'push');
            app.ApplyVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyVarsButtonPushed, true);
            app.ApplyVarsButton.Text = 'Apply';
            app.LoadVarsButton = uibutton(app.DefineinputsTab, 'push');
            app.LoadVarsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVarsButtonPushed, true);
            app.LoadVarsButton.Text = 'Load';
            app.ResetButton = uibutton(app.DefineinputsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ConfigvarButton = uibutton(app.DefineinputsTab, 'push');
            app.ConfigvarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigvarButtonPushed, true);
            app.ConfigvarButton.Text = 'Configure input variables';
            app.ReprocessdataButton = uibutton(app.DefineinputsTab, 'push');
            app.ReprocessdataButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessdataButtonPushed, true);
            app.ReprocessdataButton.Enable = 'off';
            app.ReprocessdataButton.Text = 'Reprocess data';
            app.HPLCDirectoryButton = uibutton(app.DefineinputsTab, 'push');
            app.HPLCDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @HPLCDirectoryButtonPushed, true);
            app.HPLCDirectoryButton.Text = 'HPLC Directory';
            app.NumberofinitalexperimentsSpinnerLabel = uilabel(app.DefineinputsTab);
            app.NumberofinitalexperimentsSpinnerLabel.Text = 'Number of inital experiments';
            app.InitexpSpinner = uispinner(app.DefineinputsTab);
            app.InitexpSpinner.Limits = [0 100];
            app.InitexpSpinner.Value = 10;
            app.ObjectivefunctionsListBoxLabel = uilabel(app.DefineinputsTab);
            app.ObjectivefunctionsListBoxLabel.Text = 'Objective functions';
            app.ObjectivefunctionsListBox = uilistbox(app.DefineinputsTab);
            app.ObjectivefunctionsListBox.Multiselect = 'on';
            app.ObjectivefunctionsListBox.Value = {'Item 1'};
            app.AlgorithmDropDownLabel = uilabel(app.DefineinputsTab);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.DefineinputsTab);
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.EqulibrationtimeSpinnerLabel = uilabel(app.DefineinputsTab);
            app.EqulibrationtimeSpinnerLabel.Text = 'Equlibration time';
            app.EqulibrationtimeSpinner = uispinner(app.DefineinputsTab);
            app.EqulibrationtimeSpinner.Value = 600;
            app.DatafilenameEditFieldLabel = uilabel(app.DefineinputsTab);
            app.DatafilenameEditFieldLabel.Text = 'Data file name';
            app.DatafilenameEditField = uieditfield(app.DefineinputsTab, 'text');
            app.DatafilenameEditField.Value = 'test';
            app.SamplePositionEditFieldLabel = uilabel(app.DefineinputsTab);
            app.SamplePositionEditFieldLabel.Visible = 'off';
            app.SamplePositionEditFieldLabel.Text = 'Sample Position';
            app.SamplePositionEditField = uieditfield(app.DefineinputsTab, 'text');
            app.SamplePositionEditField.Visible = 'off';
            app.SamplePositionEditField.Value = 'P1-A1';
            app.InfomationTextArea = uitextarea(app.DefineinputsTab);
            app.InfomationTextArea.Editable = 'off';
            app.Step2Label = uilabel(app.DefineinputsTab);
            app.Step2Label.Text = 'Step 2: Run inital condtions';
            app.StartInitButton = uibutton(app.DefineinputsTab, 'push');
            app.StartInitButton.ButtonPushedFcn = createCallbackFcn(app, @StartInitButtonPushed, true);
            app.StartInitButton.Text = 'Start';
            app.InitLamp = uilamp(app.DefineinputsTab);
            app.PauseInitButton = uibutton(app.DefineinputsTab, 'push');
            app.PauseInitButton.ButtonPushedFcn = createCallbackFcn(app, @PauseInitButtonPushed, true);
            app.PauseInitButton.Enable = 'off';
            app.PauseInitButton.Text = 'Pause';
            app.Step3Label = uilabel(app.DefineinputsTab);
            app.Step3Label.Text = 'Step 3: Run optimisation';
            app.StartOptButton = uibutton(app.DefineinputsTab, 'push');
            app.StartOptButton.ButtonPushedFcn = createCallbackFcn(app, @StartOptButtonPushed, true);
            app.StartOptButton.Enable = 'off';
            app.StartOptButton.Text = 'Start';
            app.OptLamp = uilamp(app.DefineinputsTab);
            app.PauseOptButton = uibutton(app.DefineinputsTab, 'push');
            app.PauseOptButton.ButtonPushedFcn = createCallbackFcn(app, @PauseOptButtonPushed, true);
            app.PauseOptButton.Enable = 'off';
            app.PauseOptButton.Text = 'Pause';
            app.AutostartCheckBox = uicheckbox(app.DefineinputsTab);
            app.AutostartCheckBox.Text = 'Auto start';
            app.AutostartCheckBox.Value = true;
            app.NumberofexperimentsSpinnerLabel = uilabel(app.DefineinputsTab);
            app.NumberofexperimentsSpinnerLabel.Text = 'Number of experiments';
            app.OptexpSpinner = uispinner(app.DefineinputsTab);
            app.OptexpSpinner.Limits = [0 100];
            app.OptexpSpinner.Value = 30;
            app.WavelengthSingle = uispinner(app.DefineinputsTab);
            app.WavelengthSingle.Step = 2;
            app.WavelengthSingle.Value = 254;
            app.WavelengthMultiLB = uispinner(app.DefineinputsTab);
            app.WavelengthMultiLB.Step = 2;
            app.WavelengthMultiLB.Limits = [190 400];
            app.WavelengthMultiLB.Visible = 'off';
            app.WavelengthMultiLB.Value = 220;
            app.ButtonGroup = uibuttongroup(app.DefineinputsTab);
            app.SinglewavelengthmonitorButton = uiradiobutton(app.ButtonGroup);
            app.SinglewavelengthmonitorButton.Text = 'Single wavelength monitor';
            app.MultiwavelengthmonitorButton = uiradiobutton(app.ButtonGroup);
            app.MultiwavelengthmonitorButton.Visible = 'off';
            app.MultiwavelengthmonitorButton.Text = 'Multi wavelength monitor ';
            app.MultiwavelengthmonitorButton.Value = true;
            app.VWDmonitorButton = uiradiobutton(app.ButtonGroup);
            app.VWDmonitorButton.Text = 'VWD monitor';
            app.WavelengthMultiUB = uispinner(app.DefineinputsTab);
            app.WavelengthMultiUB.Step = 2;
            app.WavelengthMultiUB.Limits = [190 400];
            app.WavelengthMultiUB.Visible = 'off';
            app.WavelengthMultiUB.Value = 280;
            app.ReprocessfilesButton = uibutton(app.DefineinputsTab, 'push');
            app.ReprocessfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessfilesButtonPushed, true);
            app.ReprocessfilesButton.Text = 'Reprocess files';
            app.RefwavelengthSpinnerLabel = uilabel(app.DefineinputsTab);
            app.RefwavelengthSpinnerLabel.Visible = 'off';
            app.RefwavelengthSpinnerLabel.Text = 'Ref wavelength';
            app.RefwavelengthSpinner = uispinner(app.DefineinputsTab);
            app.RefwavelengthSpinner.Visible = 'off';
            app.CalcuateallresponcefunctionsCheckBox = uicheckbox(app.DefineinputsTab);
            app.CalcuateallresponcefunctionsCheckBox.Text = 'Calcuate all responce functions?';
            app.SlopethresholdEditFieldLabel = uilabel(app.DefineinputsTab);
            app.SlopethresholdEditFieldLabel.Text = 'Slope threshold';
            app.SlopethresholdEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.SlopethresholdEditField.Value = 0.05;
            app.AmpthresholdEditFieldLabel = uilabel(app.DefineinputsTab);
            app.AmpthresholdEditFieldLabel.Text = 'Amp threshold';
            app.AmpthresholdEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.AmpthresholdEditField.Value = 15;
            app.SmoothwidthEditFieldLabel = uilabel(app.DefineinputsTab);
            app.SmoothwidthEditFieldLabel.Text = 'Smooth width';
            app.SmoothwidthEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.SmoothwidthEditField.Value = 1;
            app.PeakgroupEditFieldLabel = uilabel(app.DefineinputsTab);
            app.PeakgroupEditFieldLabel.Text = 'Peak group';
            app.PeakgroupEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.PeakgroupEditField.Value = 5;
            app.VWDMonitorNumber = uispinner(app.DefineinputsTab);
            app.VWDMonitorNumber.Step = 2;
            app.VWDMonitorNumber.Value = 1;
            app.ButtonGroup_2 = uibuttongroup(app.DefineinputsTab);
            app.BuiltindetectionButton = uiradiobutton(app.ButtonGroup_2);
            app.BuiltindetectionButton.Text = 'Built in detection';
            app.BuiltindetectionButton.Value = true;
            app.ChemstationdetectionButton = uiradiobutton(app.ButtonGroup_2);
            app.ChemstationdetectionButton.Text = 'Chemstation detection';
            app.HeightcutoffEditFieldLabel = uilabel(app.DefineinputsTab);
            app.HeightcutoffEditFieldLabel.Text = 'Height cut off';
            app.HeightcutoffEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.HeightcutoffEditField.Value = 5;
            app.AreacutoffEditFieldLabel = uilabel(app.DefineinputsTab);
            app.AreacutoffEditFieldLabel.Text = 'Area cut off';
            app.AreacutoffEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.AreacutoffEditField.Value = 50;
            app.DatavisulisationTab = uitab(app.TabGroup);
            app.DatavisulisationTab.Title = 'Data visulisation';
            app.GraphAxes = uiaxes(app.DatavisulisationTab);
            title(app.GraphAxes, 'HPLC')
            xlabel(app.GraphAxes, 'Time / min')
            ylabel(app.GraphAxes, 'Intensity')
            app.HPLCListBox = uilistbox(app.DatavisulisationTab);
            app.HPLCListBox.Items = {};
            app.HPLCListBox.Multiselect = 'on';
            app.HPLCListBox.ValueChangedFcn = createCallbackFcn(app, @HPLCListBoxValueChanged, true);
            app.HPLCListBox.Value = {};
            app.Switchgraphstate = uiswitch(app.DatavisulisationTab, 'slider');
            app.Switchgraphstate.Items = {'1', '2'};
            app.Switchgraphstate.ValueChangedFcn = createCallbackFcn(app, @SwitchgraphstateValueChanged, true);
            app.Switchgraphstate.Enable = 'off';
            app.Switchgraphstate.Value = '1';
            app.ToggledebugCheckBox = uicheckbox(app.DatavisulisationTab);
            app.ToggledebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToggledebugCheckBoxValueChanged, true);
            app.ToggledebugCheckBox.Enable = 'off';
            app.ToggledebugCheckBox.Text = 'Toggle debug mode';
            app.VarListBox = uilistbox(app.DatavisulisationTab);
            app.VarListBox.Items = {};
            app.VarListBox.Multiselect = 'on';
            app.VarListBox.ValueChangedFcn = createCallbackFcn(app, @VarListBoxValueChanged, true);
            app.VarListBox.Value = {};
            app.PlotparetodataCheckBox = uicheckbox(app.DatavisulisationTab);
            app.PlotparetodataCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotparetodataCheckBoxValueChanged, true);
            app.PlotparetodataCheckBox.Enable = 'off';
            app.PlotparetodataCheckBox.Text = 'Plot parito data';
            app.Step3Label_2 = uilabel(app.DatavisulisationTab);
            app.Step3Label_2.Text = 'Step 4: Analyse data';
            app.WavelengthSpinnerLabel = uilabel(app.DatavisulisationTab);
            app.WavelengthSpinnerLabel.Text = 'Wavelength';
            app.WavelengthSpinner = uispinner(app.DatavisulisationTab);
            app.WavelengthSpinner.Step = 2;
            app.WavelengthSpinner.Limits = [190 400];
            app.WavelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @WavelengthSpinnerValueChanged, true);
            app.WavelengthSpinner.Value = 254;
            app.ThreeDSet = uicheckbox(app.DatavisulisationTab);
            app.ThreeDSet.ValueChangedFcn = createCallbackFcn(app, @ThreeDSetValueChanged, true);
            app.ThreeDSet.Visible = 'off';
            app.ThreeDSet.Text = 'Plot all wavelengths';
            app.PlotBestIntensities = uicheckbox(app.DatavisulisationTab);
            app.PlotBestIntensities.ValueChangedFcn = createCallbackFcn(app, @PlotBestIntensitiesValueChanged, true);
            app.PlotBestIntensities.Text = 'Plot ''ideal'' chromatogram';
            app.YUpperSpinnerLabel = uilabel(app.DatavisulisationTab);
            app.YUpperSpinnerLabel.Text = 'Y Upper';
            app.YUpperSpinner = uispinner(app.DatavisulisationTab);
            app.YUpperSpinner.Value = 200;
            app.YlowerSpinnerLabel = uilabel(app.DatavisulisationTab);
            app.YlowerSpinnerLabel.Text = 'Y lower';
            app.YLowerSpinner = uispinner(app.DatavisulisationTab);
            app.YLowerSpinner.Value = -5;
            app.TimemaxSpinnerLabel = uilabel(app.DatavisulisationTab);
            app.TimemaxSpinnerLabel.Text = 'Time max';
            app.TimemaxSpinner = uispinner(app.DatavisulisationTab);
            app.TimemaxSpinner.Value = 7;
            app.OptimisationtestTab = uitab(app.TabGroup);
            app.OptimisationtestTab.Title = 'Optimisation test';
            app.liveplot = uiaxes(app.OptimisationtestTab);
            title(app.liveplot, 'Title')
            xlabel(app.liveplot, 'X')
            ylabel(app.liveplot, 'Y')
            zlabel(app.liveplot, 'Z')
            app.UIAxes = uiaxes(app.OptimisationtestTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ExperimentstofittomodelListBoxLabel = uilabel(app.OptimisationtestTab);
            app.ExperimentstofittomodelListBoxLabel.Text = 'Experiments to fit to model';
            app.ExperimentstofittomodelListBox = uilistbox(app.OptimisationtestTab);
            app.ExperimentstofittomodelListBox.Multiselect = 'on';
            app.ExperimentstofittomodelListBox.Value = {'Item 1'};
            app.ModeltofitDropDownLabel = uilabel(app.OptimisationtestTab);
            app.ModeltofitDropDownLabel.Text = 'Model to fit';
            app.ModeltofitDropDown = uidropdown(app.OptimisationtestTab);
            app.OptalgorithmDropDownLabel = uilabel(app.OptimisationtestTab);
            app.OptalgorithmDropDownLabel.Text = 'Opt algorithm';
            app.OptalgorithmDropDown = uidropdown(app.OptimisationtestTab);
            app.NumberofiterationsEditFieldLabel = uilabel(app.OptimisationtestTab);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.OptimisationtestTab, 'numeric');
            app.NumberofiterationsEditField.Value = 10;
            app.FitmodelandplotButton = uibutton(app.OptimisationtestTab, 'push');
            app.FitmodelandplotButton.ButtonPushedFcn = createCallbackFcn(app, @FitmodelandplotButtonPushed, true);
            app.FitmodelandplotButton.Text = 'Fit model and plot';
            app.SelectedresponcefunctionsListBoxLabel = uilabel(app.OptimisationtestTab);
            app.SelectedresponcefunctionsListBoxLabel.Text = 'Selected responce functions';
            app.SelectedresponcefunctionsListBox = uilistbox(app.OptimisationtestTab);
            app.SelectedresponcefunctionsListBox.Multiselect = 'on';
            app.SelectedresponcefunctionsListBox.Value = {'Item 1'};
            app.SelectedvariablesListBoxLabel = uilabel(app.OptimisationtestTab);
            app.SelectedvariablesListBoxLabel.Text = 'Selected variables';
            app.SelectedvariablesListBox = uilistbox(app.OptimisationtestTab);
            app.SelectedvariablesListBox.Multiselect = 'on';
            app.SelectedvariablesListBox.Value = {'Item 1'};
            app.TextArea = uitextarea(app.OptimisationtestTab);
            app.SimulateButton = uibutton(app.OptimisationtestTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate!';
            app.InitalLHSexperimentsEditFieldLabel = uilabel(app.OptimisationtestTab);
            app.InitalLHSexperimentsEditFieldLabel.Text = 'Inital LHS experiments';
            app.InitalLHSexperimentsEditField = uieditfield(app.OptimisationtestTab, 'numeric');
            app.InitalLHSexperimentsEditField.Value = 3;
            app.customEditFieldLabel = uilabel(app.OptimisationtestTab);
            app.customEditFieldLabel.Text = 'custom';
            app.customEditField = uieditfield(app.OptimisationtestTab, 'text');
            app.TestfunctionsDropDownLabel = uilabel(app.OptimisationtestTab);
            app.TestfunctionsDropDownLabel.Text = 'Test functions';
            app.TestfunctionsDropDown = uidropdown(app.OptimisationtestTab);
            app.xSliderLabel = uilabel(app.OptimisationtestTab);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.OptimisationtestTab);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.ySliderLabel = uilabel(app.OptimisationtestTab);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.OptimisationtestTab);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.zSliderLabel = uilabel(app.OptimisationtestTab);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.OptimisationtestTab);
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.RepeatsimulationEditFieldLabel = uilabel(app.OptimisationtestTab);
            app.RepeatsimulationEditFieldLabel.Text = 'Repeat simulation';
            app.RepeatsimulationEditField = uieditfield(app.OptimisationtestTab, 'numeric');
            app.RepeatsimulationEditField.Value = 1;
            % Show the figure after all components are created
            app.HPLC.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HPLCapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HPLC)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HPLC)
        end
    end
end